classdef OMG_MSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        STORMMSIUIFigure                matlab.ui.Figure
        STORMMSIModeSelectionPanel      matlab.ui.container.Panel
        DataProcessingButton_2          matlab.ui.control.Button
        DataAcquisitionButton_2         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        ModeTab                         matlab.ui.container.Tab
        ButtonGroupMappingSpeed         matlab.ui.container.ButtonGroup
        DisabledButton                  matlab.ui.control.RadioButton
        EnabledButton                   matlab.ui.control.RadioButton
        MappingModeButtonGroup          matlab.ui.container.ButtonGroup
        LampWithSpectro_2D              matlab.ui.control.Lamp
        DMSIButton_2                    matlab.ui.control.RadioButton
        LampWithSpectro                 matlab.ui.control.Lamp
        LampOnlyTopo                    matlab.ui.control.Lamp
        DMSIButton                      matlab.ui.control.RadioButton
        TopographyOnlyButton            matlab.ui.control.RadioButton
        PropertiesTab                   matlab.ui.container.Tab
        DimensionsLabel                 matlab.ui.control.Label
        FirstmappingpointYpositionmmEditField  matlab.ui.control.NumericEditField
        FirstmappingpointYpositionmmEditFieldLabel  matlab.ui.control.Label
        FirstmappingpointXpositionmmEditField  matlab.ui.control.NumericEditField
        FirstmappingpointXpositionmmEditFieldLabel  matlab.ui.control.Label
        SampleMaximumHeightmmEditField  matlab.ui.control.NumericEditField
        MaximumheightmmofthesampleLabel  matlab.ui.control.Label
        EstimationofmappingtimeLabel    matlab.ui.control.Label
        YmmEditField                    matlab.ui.control.NumericEditField
        YmmEditFieldLabel               matlab.ui.control.Label
        XmmEditField                    matlab.ui.control.NumericEditField
        XmmLabel                        matlab.ui.control.Label
        SurfaceoffsetmmEditField        matlab.ui.control.NumericEditField
        SurfaceoffsetmmEditFieldLabel   matlab.ui.control.Label
        ResolutionmmEditField           matlab.ui.control.NumericEditField
        ResolutionmmEditFieldLabel      matlab.ui.control.Label
        mapLabel                        matlab.ui.control.Label
        GeneratedmapfileEditField       matlab.ui.control.EditField
        GeneratedmapfileEditFieldLabel  matlab.ui.control.Label
        RobotTab                        matlab.ui.container.Tab
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        RotationLabel                   matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        DisconnectRobotButton           matlab.ui.control.Button
        StateLampRobot                  matlab.ui.control.Lamp
        RobotConnexionB                 matlab.ui.control.Button
        RobotDropDown                   matlab.ui.control.DropDown
        RobotDropDownLabel              matlab.ui.control.Label
        RobotTab_2                      matlab.ui.container.Tab
        RZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        RYEditField                     matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        RotationLabel_2                 matlab.ui.control.Label
        RXEditField                     matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        ResetButton_2                   matlab.ui.control.Button
        DisconnectRobotButton_2         matlab.ui.control.Button
        StateLampRobot_2                matlab.ui.control.Lamp
        RobotConnexionB_2               matlab.ui.control.Button
        RobotDropDown_2                 matlab.ui.control.DropDown
        RobotDropDown_2Label            matlab.ui.control.Label
        SensorTab                       matlab.ui.container.Tab
        SensorstateLamp                 matlab.ui.control.Lamp
        SensorstateLampLabel            matlab.ui.control.Label
        ConnectSensorButton             matlab.ui.control.Button
        SensorCalibrationstateLamp      matlab.ui.control.Lamp
        CalibrationstateLabel           matlab.ui.control.Label
        LaunchSensorCalibrationButton   matlab.ui.control.Button
        SensorDropDown                  matlab.ui.control.DropDown
        SensorDropDownLabel             matlab.ui.control.Label
        SourceTab                       matlab.ui.container.Tab
        PulsesperburstEditField         matlab.ui.control.NumericEditField
        PulsesperburstEditFieldLabel    matlab.ui.control.Label
        TimebetweenbeamburstssEditField  matlab.ui.control.NumericEditField
        TimebetweenbeamburstssEditFieldLabel  matlab.ui.control.Label
        LampVoltageSet                  matlab.ui.control.Lamp
        EnergylevelVSpinner             matlab.ui.control.Spinner
        EnergylevelVSpinnerLabel        matlab.ui.control.Label
        ContinuousAcquisitionCheckBox   matlab.ui.control.CheckBox
        FocaldistancemmEditField        matlab.ui.control.NumericEditField
        FocaldistancemmEditFieldLabel   matlab.ui.control.Label
        AffichageState                  matlab.ui.control.Label
        GetStateButton                  matlab.ui.control.Button
        TurnLampOffButton               matlab.ui.control.Button
        Lamp_LampONorOFF                matlab.ui.control.Lamp
        TurnLampOnButton                matlab.ui.control.Button
        AffichageTemperature            matlab.ui.control.Label
        TemperatureButton               matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        StateLampOpotek                 matlab.ui.control.Lamp
        ConnectSourceButton             matlab.ui.control.Button
        SourceDropDown                  matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
        MassSpectrometerTab             matlab.ui.container.Tab
        MassSpectrometerTrigerringDropDown  matlab.ui.control.DropDown
        MassSpectrometerTrigerringDropDownLabel  matlab.ui.control.Label
        StartTab                        matlab.ui.container.Tab
        YmmEditField_2                  matlab.ui.control.NumericEditField
        YmmEditField_2Label             matlab.ui.control.Label
        XmmEditField_2                  matlab.ui.control.NumericEditField
        XmmEditField_2Label             matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        FocaldistancemmEditField_2      matlab.ui.control.NumericEditField
        FocaldistancemmEditField_2Label  matlab.ui.control.Label
        EMERGENCYSTOPButton             matlab.ui.control.StateButton
        LaserBurstButton                matlab.ui.control.Button
        MoveRobotButton                 matlab.ui.control.Button
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditFieldLabel         matlab.ui.control.Label
        EstimatedDurationLabel          matlab.ui.control.Label
        SensorStateOffLabel             matlab.ui.control.Label
        Resolutionm500Label             matlab.ui.control.Label
        MapDimensionsmmLabel            matlab.ui.control.Label
        MappingModeTopographyOnlyLabel  matlab.ui.control.Label
        SourceStateOffLabel             matlab.ui.control.Label
        RobotStateDisconnectedLabel     matlab.ui.control.Label
        QuittoMenuButton_2              matlab.ui.control.Button
        LaunchMappingButton             matlab.ui.control.Button
        StatusWaitingLabel              matlab.ui.control.Label
        GotoMappingPositionButton       matlab.ui.control.Button
        LogTab                          matlab.ui.container.Tab
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        PrincipalComponentAnalysisLabel  matlab.ui.control.Label
        LaunchPCAButton                 matlab.ui.control.StateButton
        MaximumEditField_B              matlab.ui.control.NumericEditField
        MaximumEditField_4Label         matlab.ui.control.Label
        MinimumEditField_B              matlab.ui.control.NumericEditField
        MinimumEditField_3Label         matlab.ui.control.Label
        BlueLabel                       matlab.ui.control.Label
        MaximumEditField_G              matlab.ui.control.NumericEditField
        MaximumEditField_3Label         matlab.ui.control.Label
        MinimumEditField_G              matlab.ui.control.NumericEditField
        MinimumEditField_2Label         matlab.ui.control.Label
        GreenLabel                      matlab.ui.control.Label
        RedLabel                        matlab.ui.control.Label
        RGBDisplayLabel                 matlab.ui.control.Label
        MaximumEditField_R              matlab.ui.control.NumericEditField
        MaximumEditField_2Label         matlab.ui.control.Label
        MinimumEditField_R              matlab.ui.control.NumericEditField
        MinimumEditFieldLabel           matlab.ui.control.Label
        LaunchReconstructionButton_RGB  matlab.ui.control.Button
        DisplayAverageSpectrumButton    matlab.ui.control.Button
        LouddataonthemapSwitch          matlab.ui.control.Switch
        LouddataonthemapSwitchLabel     matlab.ui.control.Label
        AdditionnalDataExtractionFeaturesLabel  matlab.ui.control.Label
        DataReconstructionLabel         matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MapFromSpectraButton            matlab.ui.control.Button
        DisplayAreaSpectrumButton       matlab.ui.control.Button
        DisplayOneSpectrumButton        matlab.ui.control.Button
        LaunchReconstructionButton      matlab.ui.control.Button
        MaximumEditField                matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        MasslimitsMinimumEditField      matlab.ui.control.NumericEditField
        MasslimitsMinimumEditFieldLabel  matlab.ui.control.Label
        PostProcessingTab               matlab.ui.container.Tab
        LaunchGaussianRectificationButton  matlab.ui.control.Button
        MapNameNoneLabel                matlab.ui.control.Label
        MapFilePostProcessingButton     matlab.ui.control.Button
        TopographicalInterpolationLabel  matlab.ui.control.Label
        TopographicalCorrectionLabel    matlab.ui.control.Label
        PointMultiplierEditField        matlab.ui.control.NumericEditField
        PointMultiplierEditFieldLabel   matlab.ui.control.Label
        NameoftheinterpolatedmapEditField  matlab.ui.control.EditField
        NameoftheinterpolatedmapEditFieldLabel  matlab.ui.control.Label
        mapLabel_7                      matlab.ui.control.Label
        LaunchInterpolationButton       matlab.ui.control.Button
        MaximumthresholdmmEditField     matlab.ui.control.NumericEditField
        MaximumthresholdmmEditFieldLabel  matlab.ui.control.Label
        MinimumthresholdmmEditField     matlab.ui.control.NumericEditField
        MinimumthresholdmmEditFieldLabel  matlab.ui.control.Label
        mapLabel_4                      matlab.ui.control.Label
        LaunchManualRectificationButton  matlab.ui.control.Button
        NameofthecorrectedmapEditField  matlab.ui.control.EditField
        NameofthecorrectedmapEditFieldLabel  matlab.ui.control.Label
        DisplayfromexternaldataTab      matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        ColorDropDown_6                 matlab.ui.control.DropDown
        ColorDropDown_6Label            matlab.ui.control.Label
        ColorDropDown_5                 matlab.ui.control.DropDown
        ColorDropDown_5Label            matlab.ui.control.Label
        Class06EditField                matlab.ui.control.EditField
        Class06EditFieldLabel           matlab.ui.control.Label
        Class05EditField                matlab.ui.control.EditField
        Class05EditFieldLabel           matlab.ui.control.Label
        Class04EditField                matlab.ui.control.EditField
        Class04EditFieldLabel           matlab.ui.control.Label
        ColorDropDown_4                 matlab.ui.control.DropDown
        ColorDropDown_4Label            matlab.ui.control.Label
        ColorDropDown_3                 matlab.ui.control.DropDown
        ColorDropDown_3Label            matlab.ui.control.Label
        ColorDropDown_2                 matlab.ui.control.DropDown
        ColorDropDown_2Label            matlab.ui.control.Label
        BackgroundColorDropDown         matlab.ui.control.DropDown
        BackgroundColorDropDownLabel    matlab.ui.control.Label
        LaunchLDAReconstructionButton   matlab.ui.control.Button
        Class03EditField                matlab.ui.control.EditField
        Class03EditFieldLabel           matlab.ui.control.Label
        Class02EditField                matlab.ui.control.EditField
        Class02EditFieldLabel           matlab.ui.control.Label
        Class01EditField                matlab.ui.control.EditField
        Class01EditFieldLabel           matlab.ui.control.Label
        NameofthecsvfileEditField       matlab.ui.control.EditField
        NameofthecsvfileEditFieldLabel  matlab.ui.control.Label
        csvLabel                        matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        Scils_Extraction                matlab.ui.container.Tab
        ExporttoCSVSpectrumLabel        matlab.ui.control.Label
        NormalizeCSVFilesDropDown       matlab.ui.control.DropDown
        NormalizeCSVFilesDropDownLabel  matlab.ui.control.Label
        NeighbourFusionMultiplemzMLSwitch  matlab.ui.control.Switch
        NeighbourFusionMultiplemzMLSwitchLabel  matlab.ui.control.Label
        InputWatersRAWFileEditField     matlab.ui.control.EditField
        InputWatersRAWFileEditFieldLabel  matlab.ui.control.Label
        RAWLabel                        matlab.ui.control.Label
        AreaSpectrumButton              matlab.ui.control.Button
        AverageSpectrumButton           matlab.ui.control.Button
        SinglePointSpectrumButton       matlab.ui.control.Button
        ExportandcoregisterfromCSVButton  matlab.ui.control.Button
        ExportfromCSVButton             matlab.ui.control.Button
        NormaliseCSVButton              matlab.ui.control.Button
        ExporttoCSVButton               matlab.ui.control.Button
        BinningEditField                matlab.ui.control.NumericEditField
        BinningEditFieldLabel           matlab.ui.control.Label
        MaxMzEditField                  matlab.ui.control.NumericEditField
        MaxMzEditFieldLabel             matlab.ui.control.Label
        MinMzEditField                  matlab.ui.control.NumericEditField
        MinMzEditFieldLabel             matlab.ui.control.Label
        ExporttoCSVFullExperimentLabel  matlab.ui.control.Label
        Exportto3DfilePLYfromCSVLabel   matlab.ui.control.Label
        ExporttomzMLLabel               matlab.ui.control.Label
        LaunchExtractionButton          matlab.ui.control.Button
        LogTab_2                        matlab.ui.container.Tab
        TextArea2                       matlab.ui.control.TextArea
        TextArea2Label                  matlab.ui.control.Label
        STORMMSINewmatfilePanel         matlab.ui.container.Panel
        MapFileButton_NewMat            matlab.ui.control.Button
        AspirationShiftsEditField       matlab.ui.control.NumericEditField
        AspirationShiftsEditFieldLabel  matlab.ui.control.Label
        NeighbouringScansEditField      matlab.ui.control.NumericEditField
        NeighbouringScansEditFieldLabel  matlab.ui.control.Label
        NoiseThresholdEditField         matlab.ui.control.NumericEditField
        NoiseThresholdEditFieldLabel    matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        TimebetweenburstssEditField     matlab.ui.control.NumericEditField
        TimebetweenburstssEditFieldLabel  matlab.ui.control.Label
        StartThresholdEditField         matlab.ui.control.NumericEditField
        StartThresholdEditFieldLabel    matlab.ui.control.Label
        PlotChromatogramButton          matlab.ui.control.Button
        mzXMLFileButton                 matlab.ui.control.Button
        ScanSelectionMethodDropDown     matlab.ui.control.DropDown
        ScanSelectionMethodDropDownLabel  matlab.ui.control.Label
        AspirationTimesEditField        matlab.ui.control.NumericEditField
        AspirationTimesEditFieldLabel   matlab.ui.control.Label
        FusionPercentageSlider          matlab.ui.control.Slider
        FusionPercentageSliderLabel     matlab.ui.control.Label
        OutputLabel                     matlab.ui.control.Label
        InternalTriggeringSwitch        matlab.ui.control.Switch
        InternalTriggeringSwitchLabel   matlab.ui.control.Label
        ScanSelectionButton             matlab.ui.control.Button
        matLabel                        matlab.ui.control.Label
        GeneratedmatFileEditField       matlab.ui.control.EditField
        GeneratedmatFileEditFieldLabel  matlab.ui.control.Label
        BinningwindowsizeEditField      matlab.ui.control.NumericEditField
        BinningwindowsizeEditFieldLabel  matlab.ui.control.Label
        GeneratematFileButton           matlab.ui.control.Button
        ScanSelectionLabel              matlab.ui.control.Label
        InputFilesLabel                 matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        STORMMSIDataProcessingPanel     matlab.ui.container.Panel
        MatFileButton                   matlab.ui.control.Button
        MapFileButton                   matlab.ui.control.Button
        StateLabel                      matlab.ui.control.Label
        QuittoMenuButton                matlab.ui.control.Button
        NewMatFileButton                matlab.ui.control.Button
    end

    properties (Access = private)
        method;  

        % The following properties are all set in the interface
        mapping_mode
        map_res 
        map_x % Pretty sure some of these properties exist elsewhere, but 
        % there is value in having a unified system
        map_y 
        mzxml_name % For mat file creation
        mzxml_path % For mat file creation
        mzxml % Bridge to the older style of properties, will get cleaned up soon
        
        % The following state flags are used to check on several
        % components of the interface and modify callback's behaviours
        % accordingly
        robot_state = 0 % 0:Disconnected 1:Connected 2:Error 
        sensor_state = 0 % 0:Disconnected 1:Connected 2:Calibrated
        source_state = 0 % 0:Disconnected 1:Connected 2:Ready (Lamp On)
        gui_acquisition_state = 0 % 0:Not ready for imaging 1:Ready for topography 2:Ready for 2D MSI 3:Ready for 3D MSI 
    end
    
    properties (Access = public) % May replace global variables
        % The following properties are all set in the interface        
        map_name = [];
        map_pp_name = []; % For topographical post-processing
        map_path = [];
        map_pp_path = [];% For topographical post-processing      
        mat_name = [];
        mat_path = [];
        
        workdir = pwd; % Working directory of the app/exe file. Could be used for I/O operations
        rotation = [app.RXEditField.Value app.RYEditField.Value app.RZEditField.Value] % Default rotation angle for the robot arm, changed in the robot tab
    end
    
    methods (Access = private)    
        
        function check_project(app)
            if isempty(app.map_path) == 0 && isempty(app.mat_path) == 0
                project_map = find_project(app, app.map_path);
                project_mat = find_project(app, app.mat_path);

                if strcmp(project_map, project_mat) == 1
                    disp("All clear!")
                    app.StateLabel.Text = "All clear!";
                    app.StateLabel.FontColor = [0.0 0.7 0.0];
                    enable_processing(app)
                    enable_map_processing(app)
                    app.MatFileButton.FontColor = [0.0 0.7 0.0];
                    app.MapFileButton.FontColor = [0.0 0.7 0.0];
                else
                    disp("Warning: Project mismatch. Chose files from identical projects to avoid issues during data processing.")
                    app.StateLabel.Text = "Warning: Project mismatch. Chose files from identical projects to avoid issues during data processing.";
                    app.StateLabel.FontColor = [0.7 0.3 0.0];
                    disable_processing(app)
                    enable_map_processing(app)
                    app.MatFileButton.FontColor = [0.7 0.3 0.0];
                    app.MapFileButton.FontColor = [0.7 0.3 0.0];
                end
            elseif isempty(app.map_path) == 0
                disp("Warning: No Mat file selected. Reduced interface functionality.")
                app.StateLabel.Text = "Warning: No Mat file selected. Reduced interface functionality.";
                app.StateLabel.FontColor = [0.7 0.3 0.0];
                disable_processing(app)
                enable_map_processing(app)
            else
                if isempty(app.mat_path) == 0
                    msg = "Please select a Map file to begin data processing.";
                    app.MatFileButton.FontColor = [0.0 0.7 0.0];
                else
                    msg = "Please select a Mat file and a map file to begin data processing.";
                    app.MatFileButton.FontColor = [0.7 0.0 0.0];
                end
                disp(msg)
                app.StateLabel.Text = msg;
                app.StateLabel.FontColor = [0.7 0.0 0.0];
                app.MapFileButton.FontColor = [0.7 0.0 0.0];
                disable_processing(app)
                disable_map_processing(app)
            end    
        end
        
        function enable_processing(app)
            % Enable the processing tab
            app.MasslimitsMinimumEditField.Enable = "on";
            app.MaximumEditField.Enable = "on";
            app.LaunchReconstructionButton.Enable = "on";
            app.MinEditField.Enable = "on";
            app.MaxEditField.Enable = "on";
            app.LouddataonthemapSwitch.Enable = "on";
            app.MapFromSpectraButton.Enable = "on";
            app.DisplayOneSpectrumButton.Enable = "on";
            app.DisplayAreaSpectrumButton.Enable = "on";
            app.DisplayAverageSpectrumButton.Enable = "on";
            app.MinimumEditField_R.Enable = "on";
            app.MinimumEditField_G.Enable = "on";
            app.MinimumEditField_B.Enable = "on";
            app.MaximumEditField_R.Enable = "on";
            app.MaximumEditField_G.Enable = "on";
            app.MaximumEditField_B.Enable = "on";
            app.LaunchReconstructionButton_RGB.Enable = "on";
            app.LaunchPCAButton.Enable = "on";

            % Enable the data exportation tab
            app.InputWatersRAWFileEditField.Enable = "on";
            app.InputWatersRAWFileEditFieldValueChanged(app);
            app.SinglePointSpectrumButton.Enable = "on";
            app.AverageSpectrumButton.Enable = "on";
            app.AreaSpectrumButton.Enable = "on";
            app.ExporttoCSVButton.Enable = "on";
            app.MinMzEditField.Enable = "on";
            app.MaxMzEditField.Enable = "on";
            app.BinningEditField.Enable = "on";
        end
        
        function disable_processing(app)
            % Disable the processing tab
            app.MasslimitsMinimumEditField.Enable = "off";
            app.MaximumEditField.Enable = "off";
            app.LaunchReconstructionButton.Enable = "off";
            app.MinEditField.Enable = "off";
            app.MaxEditField.Enable = "off";
            app.LouddataonthemapSwitch.Enable = "off";
            app.MapFromSpectraButton.Enable = "off";
            app.DisplayOneSpectrumButton.Enable = "off";
            app.DisplayAreaSpectrumButton.Enable = "off";
            app.DisplayAverageSpectrumButton.Enable = "off";
            app.MinimumEditField_R.Enable = "off";
            app.MinimumEditField_G.Enable = "off";
            app.MinimumEditField_B.Enable = "off";
            app.MaximumEditField_R.Enable = "off";
            app.MaximumEditField_G.Enable = "off";
            app.MaximumEditField_B.Enable = "off";
            app.LaunchReconstructionButton_RGB.Enable = "off";
            app.LaunchPCAButton.Enable = "off";

            % Enable the data exportation tab
            app.InputWatersRAWFileEditField.Enable = "off";
            app.SinglePointSpectrumButton.Enable = "off";
            app.AverageSpectrumButton.Enable = "off";
            app.AreaSpectrumButton.Enable = "off";
            app.NeighbourFusionMultiplemzMLSwitch.Enable = "off";
            app.ExporttoCSVButton.Enable = "off";
            app.LaunchExtractionButton.Enable = "off";
            app.MinMzEditField.Enable = "off";
            app.MaxMzEditField.Enable = "off";
            app.BinningEditField.Enable = "off";
        end
        
        function enable_map_processing(app)
            app.MapFilePostProcessingButton.Enable = "on";
            app.NameofthecorrectedmapEditField.Enable = "on";
            NameofthecorrectedmapEditFieldValueChanged(app)
            app.NameoftheinterpolatedmapEditField.Enable = "on";
            NameoftheinterpolatedmapEditFieldValueChanged(app)
        end

        function disable_map_processing(app)
            app.MapFilePostProcessingButton.Enable = "off";
            app.NameofthecorrectedmapEditField.Enable = "off";
            app.MinimumthresholdmmEditField.Enable = "off";
            app.MaximumthresholdmmEditField.Enable = "off";
            app.LaunchManualRectificationButton.Enable = "off";            
            app.NameoftheinterpolatedmapEditField.Enable = "off";
            app.PointMultiplierEditField.Enable = "off";
            app.LaunchInterpolationButton.Enable = "off";
        end
        
        function project = find_project(app, file_path)
            cut_path = strsplit(file_path, "\");
            project = cut_path(end-2);
        end
        
        function update_pp_label(app)
            app.MapNameNoneLabel.Text = strcat("Map Name: ", app.map_pp_name);
        end
        
        function [mat_name_local, map_name_local, map] = get_file_names(app, load_map)
            if isempty(app.mat_name) == 0
                mat_name_local = strcat(app.mat_path, app.mat_name,'.mat');
            else
                mat_name_local = [];
            end

            if isempty(app.map_name) == 0
                map_name_local = strcat(app.map_path, app.map_name,'.map');
            else
                map_name_local = [];
            end 

            if load_map 
                map = load_map_fcn(map_name_local); % Note: Loading mat 
                % files that way would not work as they wold be instance 
                % within the function, and not the app as a whole, meaning 
                % that they should be reloaded everytime a function needs 
                % them.
            else
                map = [];
            end
        end
        
        function mzxml_map_loaded(app)
            ScanSelectionMethodDropDownValueChanged(app)
            app.ScanSelectionButton.Enable = "on";
            app.ScanSelectionMethodDropDown.Enable = "on";                                
        end
        
        function [map_name_local, map] = get_map_name(app, load_map)
            if isempty(app.map_name) == 0
                map_name_local = strcat(app.map_path, app.map_name,'.map');
            else
                check_isempty(app, app.map_name, ".map")
                error("No map file has been found. Please load a map file and try again.")
            end 

            if load_map 
                map = load_map_fcn(map_name_local); % Note: Loading mat 
                % files that way would not work as they wold be instance 
                % within the function, and not the app as a whole, meaning 
                % that they should be reloaded everytime a function needs 
                % them.
            else
                map = [];
            end
        end
        
        function update_robot_rotation(app)
            xval = app.RXEditField.Value;
            yval = app.RYEditField.Value;
            zval = app.RZEditField.Value;
            app.rotation = [xval, yval, zval];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STORMMSIUIFigure.Visible = "off";
            app.STORMMSIUIFigure.Name = "STORM-MSI";
            movegui(app.STORMMSIUIFigure, 'center') % Centers the interface on the 
            % screen. Causes some jittering when switching windows.
            app.STORMMSIUIFigure.Icon = "STORM-MSI_Logo.png";
            global info_log;
            info_log = "";
            
            if not(isdeployed)
                disp('STORM-MSI is not deployed as an executable. Adding necessary files to the workspace.')
                addpath(genpath("code/"))
                addpath(genpath("files/")) % I feel like this approach, 
                % while less elegant, is much more robust as it does not require 
                % adding .m to the workspace in the first place
            else
                disp('STORM-MSI is deployed as an executable.')
            end            
            
            % Initialise acquisition properties
            app.mapping_mode = "Topography Only";
            app.map_x = 16; 
            app.map_y = 16;
            app.map_res = 0.5;
            
            % Initialise hardware state properties (NOT USED YET)
            app.robot_state = 0;
            app.sensor_state = 0;
            app.source_state = 0;

            global state; % Global variable that contains all the state of the system
            state.stop_flag = 0; % security flag => the system will stop itself if state.stop_flag == 1 % formerly state.arret #TODO = verifier le fonctionnement
            state.source_connected = 0; % source trigeering connexion => if connected state.source_connected == 1 % formerly state.opo
            state.calibrated = 0; % calibration state => if the calibration is done state.calibration == 1 % formerly state.etal
            state.robot_connected = 0;  % robot connexion => if connected state.robot == 1 % formerly state.robot
            state.sensor_connected = 0;  % robot connexion => if connected state.robot == 1 % formerly state.robot

            global old_name; % Useful for map_from_spectra => Avoids loading the same data twice
            old_name = "";

            % Contains all data related to mass spectra
            app.mzxml.is_loaded = 0; % flag to know if there is any mass spectra data loaded

            OptionName = readlines("/code/method/csv_normalization_types.txt");
            app.NormalizeCSVFilesDropDown.Items = OptionName;

            OptionName = readlines("/code/hardware/robot_types.txt");
            app.RobotDropDown.Items = OptionName;

            OptionName = readlines("/code/hardware/source_types.txt");
            app.SourceDropDown.Items = OptionName;

            OptionName = readlines("/code/hardware/sensor_types.txt");
            app.SensorDropDown.Items = OptionName;

            OptionName = readlines("/code/hardware/mass_spectrometer_types.txt");
            app.MassSpectrometerTrigerringDropDown.Items = OptionName;

            OptionName = readlines("/code/method/scan_methods.txt");
            app.ScanSelectionMethodDropDown.Items = OptionName;
             
            global arduino_object % For Prism Laboratory Setup Only
            % if ~isempty(arduino_object)
            %    clear(arduino_object); Not working right now
            % end
            try
                arduino_object = arduino(); % DEVNOTE: Arduinos should be more modular in their implementation
                disp(arduino_object)
            catch ME
                if strcmp(ME.identifier, 'MATLAB:UndefinedFunction')
                    disp("Warning: Install the Arduino package to use " + ...
                        "Arduino-related functionalities of STORM-MSI: " + ...
                        "https://www.mathworks.com/matlabcentral/fileexchange/47522?download=true")
                else
                    rethrow ME
                end
            end

            % This code snippet automatically corrects the workspace. Not
            % needed for the compiled version, but can probably help in the
            % MatLab environment.
%             dest = '/home/pchaillo/Documents/prism'; % chemin en dur, à bien choisir avant de décommenter cette partie
%             cd(dest);
            
            app.STORMMSIUIFigure.Visible = "on";
        
        end

        % Button pushed function: LaunchMappingButton
        function LaunchMappingButtonPushed(app, event)
%           While I would rather set conditions for the button to only be
%           enabled once every part of the chosen workflow is properly set,
%           this would cause a massive overhead with MatLab as it currently
%           is. Catching misconfiguration before it causes a mapping error
%           is therefore the next best thing. 

%           Global conditions for imaging
            if isempty(app.GeneratedmapfileEditField) == 1 
                    msg = "Please input a map name in the acquisition properties tab and try again";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18];   
                    return
            elseif app.RobotStateDisconnectedLabel.Text == "Robot State: Disconnected" 
                    msg = "Please turn the robot on and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18];
                    return
            end

%           Local conditions for imaging
            selectedButton = app.MappingModeButtonGroup.SelectedObject;
            if selectedButton == app.TopographyOnlyButton
                if app.SensorStateOffLabel.Text == "Sensor State: Off"
                    msg = "Please turn the sensor on, calibrate it if needed, and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18]; 
                    return
                end
            elseif selectedButton == app.DMSIButton  % 3D MSI
                if app.SensorStateOffLabel.Text == "Sensor State: Off"
                    msg = "Please turn the sensor on, calibrate it if needed, and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18]; 
                    return
                elseif app.SourceStateOffLabel.Text ~= "Source State: Lamp Ready"
                    msg = "Please turn the laser and the lamp on in the laser tab, and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18];
                    return
                end
            elseif selectedButton == app.DMSIButton_2  % 2D MSI
                if app.SourceStateOffLabel.Text ~= "Source State: Lamp Ready"
                    msg = "Please turn the laser and the lamp on in the laser tab, and try again.";
                    update_log(app, msg)
                    msgbox(msg, 'Something went wrong.', 'error')
                    app.LaunchMappingButton.FontColor = [0.64,0.08,0.18];
                    return
                end
            end
            
            app.update_robot_rotation(app);
            app.LaunchMappingButton.FontColor = [0.2 0.8 0.2];
            app.StatusWaitingLabel.Text = 'Mapping in progress...';

             % Variables initialisation
            global parameters
            global state;
            
            check_state(state, app)
            state.arret = 0; % permet arret securité total en cas de soucis

            global map; % utile ? #TODO
            global source;
            global robot;
            global sensor;

            triggering.type = app.MassSpectrometerTrigerringDropDown.Value;
            triggering.class = eval(triggering.type);

            %% Parameters => Import user parameters from the GUI
            map_name_raw = app.GeneratedmapfileEditField.Value;
            app.map_name = map_name_raw;
            check_isempty(app, map_name_raw,'.map');
            map_name_local = strcat(map_name_raw,'.map');
            parameters.mapping_step = app.ResolutionmmEditField.Value;         % mapping step in mm
            parameters.maximal_height = app.SampleMaximumHeightmmEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.x_offset = app.FirstmappingpointXpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            parameters.y_offset = app.FirstmappingpointYpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            parameters.dim_x = app.XmmEditField.Value;        % dimension of the mapping zone in the x axis, should be an even value % Even value only ???? #TODO
            parameters.dim_y = app.YmmEditField.Value;        % dimension of the mapping zone in the y axis, should be an even value
            parameters.surface_offset = app.SurfaceoffsetmmEditField.Value;        % surface offset in mm
            parameters.initial_height = app.FocaldistancemmEditField.Value + parameters.surface_offset ;          % z offset between the robot arm and the sample to be analysed

            source.voltage_value = app.EnergylevelVSpinner.Value; % With some laser, you may set the voltage you want in order to tune the intensity of the laser 
            source.time_step = app.TimebetweenbeamburstssEditField.Value; % pause time between two laser shot, in second
            source.nb_shot = app.PulsesperburstEditField.Value; % number of shot for the burst mode
            source.continuous_flag = app.ContinuousAcquisitionCheckBox.Value; % 0 => no continuous / 1 => continuous

            parameters.fast_flag = app.DisabledButton.Value; % I think it's a variable to not do repositionning if it's difficult to get height data from depth sensor

            pos_i= [parameters.x_offset   2  parameters.initial_height 180 0 180]; % initial position % TODO; Refactor positions for easier interoperability with other robots
            robot.class.set_position(pos_i);  % send the mapping initial position of the robot

            %% To select and launch the mapping code
            if app.DMSIButton.Value %% For 3D imaging with Mass Spectrometry
                time_ref = triggering.class.trigger();
                map = biologic_3D_acquisition(app, robot, sensor, source, parameters, time_ref); % do the mapping step
            elseif app.TopographyOnlyButton.Value %% For 3D acquisition only (without mass spectrometry)
                time_ref = triggering.class.trigger();
                map = topographic_acquisition(app, robot, sensor, parameters,time_ref); 
            elseif app.DMSIButton_2.Value  %% For 2D imaging with Mass Spectrometry
                time_ref = triggering.class.trigger();
                map = biologic_2D_acquisition(app, robot, source, parameters, time_ref);
            else
                update_log(app, 'ERROR - Mapping choice problem');
                app.StatusWaitingLabel.Text = "ERROR - Mapping choice problem";
                app.StatusWaitingLabel.FontColor = [0.64 0.08 0.18];
            end

            %[ map.r, nb_err ] = map_rectification(map.i); % correction of
            % the map %%( delete impossible values ) #TOCHOOSE
            map.z = map.i; % two time the same ! #TODO
            figure()
            subplot(2,4,[1,2,5,6]), mesh(map.x,map.y,map.i); % initial map
            axis equal
            subplot(2,4,[3,4,7,8]), mesh(map.x,map.y,map.z); % rectified map
            axis equal
            
            create_project(map_name_raw)
            app.map_path = strcat("files/", map_name_raw, "/", "map_files/");
            time_rec = record_map(map, map_name_local, app.map_path);
            
            time_text = strcat('Map finished. Recommended reconstruction time : ', time_rec);
            app.StatusWaitingLabel.Text = time_text;
            app.StatusWaitingLabel.FontColor = [0 0 0];
            app.LaunchMappingButton.FontColor = [0.00 0.00 0.00];
            
            % Once the map is done, go back to the 1st point position
            initial_position= [parameters.x_offset  parameters.y_offset  parameters.initial_height 180 0 180];
            robot.class.set_position(initial_position);  % Returns the robot to its starting position
        end

        % Button pushed function: RobotConnexionB
        function RobotConnexionBButtonPushed(app, event)
            global state;
            check_state(state, app);

            global robot; % ou fermeture de la connexion
            
            robot.type = app.RobotDropDown.Value;
            robot.class = eval(robot.type);
            robot.class.init(app);

            app.StateLampRobot.Color = [0 1 0];
            app.LampOnlyTopo.Color = [0 1 0];

            state.robot_connected = 1 ;
            
            app.DisconnectButton.Enable = "on";
            app.XPositionEditField.Enable = "on";
            app.YPositionEditField.Enable = "on";
            app.ZPositionEditField.Enable = "on";
            app.MoveRobotButton.Enable = "on";

            app.RobotConnexionB.Enable = "off";
            app.DisconnectRobotButton.Enable = "on";
            app.RobotStateDisconnectedLabel.Text = "Robot State: Connected";
            app.ResetButton.Enable = "on";
        end

        % Button pushed function: ConnectSourceButton
        function ConnectSourceButtonPushed(app, event)
            global state;
            check_state(state, app)

            app.StateLampOpotek.Color = [0 0 1];

            global source

            source.voltage_value = app.EnergylevelVSpinner.Value; % Put on source variable instead ?  
            source.type = app.SourceDropDown.Value;

            app.StateLampOpotek.Color = [0 0 1];

            update_log(app, "Laser Energy Level : " + source.voltage_value)
            update_log(app, "Laser Type : " + source.type)

            source.class = eval(source.type);

            source.class.init(app);

            [state_string, state_double] = source.class.get_state(app);

            % State double : describe the state of the laser => 
            % 0 - Not connected
            % 1 - Connected - Ready to turn the lamp on
            % 2 - Lamp on - Ready for shooting
            % -1 - Emergency - Problem during communication
    
            if state_double == 1 || state_double == 2
                app.StateLampOpotek.Color = [0 1 0];
            end

            app.AffichageState.Text = state_string;

            if source.type == "Opotek IR Radiant"
                wdog_loop = 1; % There is a watchdog on the Opotek laser (also on Opolette ?) that puts the laser in security mode if no message is received within four seconds. In order to avoid this, as message is sent every two seconds. If the laser is in security mode, please restart it in order to be able to command it again.
                while wdog_loop == 1
                    [state_string, state_double]= source.class.get_state(app);
                    if state_string == -1
                        wdog_loop = 0;
                        update_log(app, 'Loose connexion : no more Opotek feedback')
                    end
                    app.AffichageState.Text = state_string;
                    pause(2);
                end
            end
            
            app.DisconnectButton.Enable = "on";
            app.TemperatureButton.Enable = "on";
            app.TurnLampOnButton.Enable = "on";
            app.ConnectSourceButton.Enable = "off";
            app.SourceStateOffLabel.Text = "Source State: On";
            app.SourceStateOffLabel.FontColor = [0.2 0.8 0.2];

            state.source_connected = 1;
        end

        % Button pushed function: LaunchSensorCalibrationButton
        function LaunchSensorCalibrationButtonPushed(app, event)
            app.SensorCalibrationstateLamp.Color = [0 0 1];

            global robot;
            global parameters
            global state;
            global sensor;

            check_state(state, app);

%             scan.etal = 0.25; % pas d'étallonage => 0.25 mm recommandé / step for the camera calibration % variable dans l'interface % #TODO
%             sensor.class.calibration_step % faire remonter dans
%             l'interface ou en dur dans le code objet des capteurs ? #TODO
%             #TOCHOOSE
        
            parameters.x_offset = app.FirstmappingpointXpositionmmEditField.Value;         % X offset (mm) between the robot base and the robot head
            parameters.surface_offset = app.SurfaceoffsetmmEditField.Value;        % Surface offset in mm
            parameters.maximal_height = app.SampleMaximumHeightmmEditField.Value;         % Maximum height (mm) of the sample you want to map, add a little bit more than real height

            % ard = arduino(); % connect the arduino (sensor acquisition and spectro triggering)

            if sensor.class.need_calibration == 1
                calibration_array = sensor.class.calibration(robot,parameters, app); % calibration of the sensor (ILD1320-25)
                sensor.calibration_array = fix_calibration_array(app, calibration_array); % correction of the calibration ( delete false values )
            
                % Show the calibration graph
                figure()
                hold on
                for i = 1 : length(sensor.calibration_array)
                    scatter(sensor.calibration_array(1,i),sensor.calibration_array(2,i))
                end
                xlabel('Hauteur de mesure, en mm')
                ylabel('Tension mesuree')
                hold off
                app.SensorCalibrationstateLamp.Color = [0 1 0];
                app.LampOnlyTopo.Color = [0 1 0];
                state.calibrated = 1;
                if state.source_connected == 1
                    app.LampWithSpectro.Color = [0 1 0];
                end
            else 
                update_log(app, "No calibration needed for this sensor")
            app.SensorStateOffLabel.Text = "Sensor State: Calibrated";
            app.SensorStateOffLabel.FontColor = [0.2 0.8 0.2];
            end
        end

        % Button pushed function: LaserBurstButton
        function LaserBurstButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global source;

            source.nb_shot = app.PulsesperburstEditField.Value; % number of shot for the burst mode

            source.class.trigger(source.nb_shot, app)

        end

        % Button pushed function: GotoMappingPositionButton
        function GotoMappingPositionButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global parameters;
            global robot;
            
            app.update_robot_rotation(app)
            %for security check
            parameters.maximal_height = app.SampleMaximumHeightmmEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.surface_offset = app.SurfaceoffsetmmEditField.Value;        % surface offset in mm
            parameters.x_offset = app.FirstmappingpointXpositionmmEditField.Value;         % x offset (mm) between the robot base and the robot head
            parameters.initial_height = app.FocaldistancemmEditField.Value + parameters.surface_offset;          % z offset between the robot arm and the sample to be analysed

            initial_position = [parameters.x_offset  2  parameters.initial_height 180 0 180]; % Initial position % format dependant du robot #TODO
            robot.class.set_position(initial_position);  % send the initial position to the robot

        end

        % Button pushed function: TurnLampOnButton
        function TurnLampOnButtonPushed(app, event)
            app.Lamp_LampONorOFF.Color = [0 0 1];
            global source;

            temp = source.class.get_temp(app);
            text_temp = strcat('Temperature : ', num2str(temp),'°C');
            app.AffichageTemperature.Text = text_temp;

            state_string = source.class.lamp_on(app);
            [state_string, state_double] = source.class.get_state(app);
            app.AffichageState.Text = state_string;

            if state_double == 2
                app.Lamp_LampONorOFF.Color = [0 1 0];
            else
                app.Lamp_LampONorOFF.Color = [ 1 0 0];
            end

            global state;
            check_state(state, app);
            if state.calibrated == 1
                app.LampWithSpectro.Color = [0 1 0];
            end
            if state.robot_connected == 1
                app.LampWithSpectro_2D.Color = [0 1 0];
            end
            state.source_connected = 1;
            
            app.TurnLampOffButton.Enable = "on";
            app.GetStateButton.Enable = "on";
            app.FocaldistancemmEditField.Enable = "on";
            app.FocaldistancemmEditField_2.Enable = "on";
            app.EnergylevelVSpinner.Enable = "on";
            app.SourceStateOffLabel.Text = "Source State: Lamp Ready";
            app.SourceStateOffLabel.FontColor = [0.2 0.8 0.2];
            app.LaserBurstButton.Enable = "on";
            app.ContinuousAcquisitionCheckBox.Enable = "on";
            app.TimebetweenbeamburstssEditField.Enable = "on";
            app.PulsesperburstEditField.Enable = "on";
            app.TurnLampOnButton.Enable = "off";

        end

        % Button pushed function: TurnLampOffButton
        function TurnLampOffButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global source;

            state_string = source.class.lamp_off(app);
            [state_text, state_double] = source.class.get_state(app);
            if state_double == 1
                app.Lamp_LampONorOFF.Color = [1 0 0];
            else
                app.Lamp_LampONorOFF.Color = [0 1 1];
            end
            app.AffichageState.Text = state_text;

            %app.Lamp_LampONorOFF.Color = [0 1 1];
            app.SourceStateOffLabel.Text = "Source State: On";
            app.SourceStateOffLabel.FontColor = [0.8 0.3 0];
            app.TurnLampOnButton.Enable = "on";
            app.TurnLampOffButton.Enable = "off";
        end

        % Button pushed function: DisconnectRobotButton
        function DisconnectRobotButtonPushed(app, event)
            app.RobotConnexionB.Enable = "on";
            app.XPositionEditField.Enable = "off";
            app.YPositionEditField.Enable = "off";
            app.ZPositionEditField.Enable = "off";
            app.MoveRobotButton.Enable = "off";
            
            global robot;
            global parameters;
            global state;
            
            check_state(state, app);
            
            %for security check
            parameters.maximal_height = app.SampleMaximumHeightmmEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.surface_offset = app.SurfaceoffsetmmEditField.Value;        % Surface offset in mm

            robot.class.go_to_rest_position(app); % Too many input argument ? #TODO

            app.StateLampRobot.Color = [1 0 0];

            
            state.robot_connected = 0;
            app.LampWithSpectro.Color = [1 0 0];
            app.LampWithSpectro_2D.Color = [1 0 0];
            app.LampOnlyTopo.Color = [1 0 0];
            
            app.RobotConnexionB.Enable = "on";
            app.DisconnectRobotButton.Enable = "off";
            app.RobotStateDisconnectedLabel.Text = "Robot State: Disconnected";
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global source; 
            source.class.disconnect(app);
            app.StateLampOpotek.Color = [1 0 0];
            app.SourceStateOffLabel.Text = "Source State: Off";
            app.SourceStateOffLabel.FontColor = [1 0 0];
            app.TurnLampOffButton.Enable = "off";
            app.TurnLampOnButton.Enable = "off";
            app.ConnectSourceButton.Enable = "on";
        end

        % Button pushed function: TemperatureButton
        function TemperatureButtonPushed(app, event)
            global state;
            check_state(state, app)

            global source 
            temp = source.class.get_temp(app);
            text_temp = strcat('Temperature :  ', num2str(temp),'°C');
            app.AffichageTemperature.Text = text_temp;
        end

        % Button pushed function: MoveRobotButton
        function MoveRobotButtonPushed(app, event)
            global state;
            check_state(state, app)
            
            global robot;
            global parameters;

            %for security check
            parameters.maximal_height = app.SampleMaximumHeightmmEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.surface_offset = app.SurfaceoffsetmmEditField.Value;        % surface offset in mm

            x = app.XPositionEditField.Value;
            y = app.YPositionEditField.Value;
            z = app.ZPositionEditField.Value;
            position = [x  y  z 180 0 180];
            robot.class.set_position(position);  % send the mapping initial position of the robot

        end

        % Button pushed function: GetStateButton
        function GetStateButtonPushed(app, event)
            global source;
            global state;
            check_state(state, app)
            [state_text, state_double] = source.class.get_state(app);
            if state_double == -1
                app.Lamp_LampONorOFF.Color = [1 0 0];
                app.LampWithSpectro.Color = [1 0 0];
                app.StateLampOpotek.Color = [1 0 0];
                state.source_connected = 0;
            elseif state_double == 0
                app.Lamp_LampONorOFF.Color = [1 0 0];
                app.LampWithSpectro.Color = [1 0 0];
                app.StateLampOpotek.Color = [1 0 0];
                state.source_connected = 0;
            elseif state_double == 1
                app.Lamp_LampONorOFF.Color = [1 0 0];
                app.StateLampOpotek.Color = [0 1 0];
                state.source_connected = 1;
            end

            app.AffichageState.Text = state_text;

        end

        % Value changed function: EnergylevelVSpinner
        function EnergylevelVSpinnerValueChanged(app, event)
            global source;

            app.LampVoltageSet.Color = [0 0 1];

            source.class.voltage_value = app.EnergylevelVSpinner.Value;
            real_voltage = source.class.set_voltage(source.class.voltage_value, app);
            if real_voltage ~= source.voltage_value
                app.LampVoltageSet.Color = [1 0 0];
            else
                app.LampVoltageSet.Color = [0 1 0];
            end

        end

        % Selection changed function: ButtonGroupMappingSpeed
        function ButtonGroupMappingSpeedSelectionChanged(app, event)
            global parameters;
            parameters.fast_flag = app.DisabledButton.Value;
        end

        % Close request function: STORMMSIUIFigure
        function STORMMSIUIFigureCloseRequest(app, event)
            %% Disconnects the robot
            global robot;
            global parameters
            %for security check
            parameters.maximal_height = app.SampleMaximumHeightmmEditField.Value;         % maximum height (mm) of the sample you want to map, add a little bit more than real height
            parameters.surface_offset = app.SurfaceoffsetmmEditField.Value;        % surface offset in mm

            global state;
            if isfield(state,'robot')
                if state.robot_connected == 1
                    robot.class.disconnect(app);
                end
            end
            app.StateLampRobot.Color = [1 0 0];

            global state;
            state.robot_connected = 0;
            app.LampWithSpectro.Color = [1 0 0];
            app.LampWithSpectro_2D.Color = [1 0 0];
            app.LampOnlyTopo.Color = [1 0 0];

            global arduino_object
            global sensor
            clear("arduino_object")
            clear("sensor")
%             clear all % clear app so you may not close it afterward ?

            %% Close the app
            delete(app)

        end

        % Button pushed function: GeneratematFileButton
        function GeneratematFileButtonPushed(app, event)
            pixels_scans = app.mzxml.pixels_scans; % Please change this to pixel_scans

            win = app.BinningwindowsizeEditField.Value;

            mat_r = app.GeneratedmatFileEditField.Value;
            check_isempty(app, mat_r,'.mat')
            mat_name_local = strcat(mat_r,'.mat');

%             l = length(pixels_scans);

            pixels_scans_processed = preprocess_all_image(app, pixels_scans, win);

            threshold_begin = app.StartThresholdEditField.Value;
            time_step = app.TimebetweenburstssEditField.Value;
            update_log(app, app.NoiseThresholdEditField.Value)
            noise_threshold = app.NoiseThresholdEditField.Value;

            tolerance = app.FusionPercentageSlider.Value;

            intern_trig = app.InternalTriggeringSwitch.Value;
            if intern_trig(1:2) == 'On'
                intern_flag = 1;
            else
                intern_flag = 0;
            end

            pixels_scans = pixels_scans_processed; 
            pixels_scans(1).comment = [threshold_begin, noise_threshold, time_step,tolerance,intern_flag ]; % Stores the parameters along with the data

            save(mat_name_local, 'pixels_scans', '-v7.3')
            app.mat_path = strrep(app.map_path, "map_files", "mat_files");
            app.mat_name = mat_r;
            movefile(mat_name_local, app.mat_path);
            
            msg = strcat("Mat file recorded in \files\", find_project(app, app.mat_path), "\mat_files\");
            update_log(app, msg)
            check_project(app)
            ExitButtonPushed(app, event) 
        end

        % Value changed function: MasslimitsMinimumEditField
        function MasslimitsMinimumEditFieldValueChanged(app, event)
            limits = app.MasslimitsMinimumEditField.Value;

            mz_shift = 0.25; % usual difference between the mass limits

            app.MaximumEditField.Value = limits + mz_shift;
        end

        % Button pushed function: LaunchReconstructionButton
        function LaunchReconstructionButtonPushed(app, event)
            [mat_name_local, ~, map] = get_file_names(app, true);
            
            load(mat_name_local);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;
            limits = [l_min l_max];

            fprintf('(%s) - The map file is loaded \n', datestr(now,'HH:MM:SS'));
            fprintf('(%s) - Starting to build the intensity matrix\n', datestr(now,'HH:MM:SS'));

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            if isfield(map,'time')
                time_flag = 1;
            else
                time_flag = 0;
                map_time = 0;
            end

            [ pixels_ind, scans_ind, pixels_mz, fusion_list] = data_on_map(app, pixels_scans,map,limits,map.time,time_flag,loud_flag);

            [ map.x,map.y,map.z,pixels_mz  ] = fix_border(map.x,map.y,map.z,pixels_mz,pixels_ind);

            fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));
            title_str = strcat( "Biometric map, mass limits: ", num2str(limits(1)) ,'  -  ',num2str(limits(2)) );
            display_mz_map(map,pixels_mz,title_str)

        end

        % Callback function
        function LaunchTimeEstimationButtonPushed(app, event)
            threshold_begin = app.StartThresholdEditField.Value;
            noise_threshold = app.NoiseThresholdEditField.Value; 

            fprintf('(%s) - Filtering the mzXML file to keep only the image related spectra\n', datestr(now,'HH:MM:SS'));
            %             [bio_dat ,time,g] = load_mzXML_p4(mzXMLStruct,threshold_begin,t_b); % take only the useful informations
            time = time_from_peaks(app, app.mzxml.contents, threshold_begin, noise_threshold); % take only the useful informations

            time_str = num2str(time);
            time_str2 = strcat(time_str, ' seconds');
            app.TimeValue.Text = time_str2;
        end

        % Button pushed function: DisplayOneSpectrumButton
        function DisplayOneSpectrumButtonPushed(app, event)
            [mat_name_local, ~, map] = get_file_names(app,  true);
            load(mat_name_local)         

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            display_one_spectrum(app, pixels_scans, map, mat_name_local, limits,loud_data)
        end

        % Button pushed function: DisplayAreaSpectrumButton
        function DisplayAreaSpectrumButtonPushed(app, event)
            [mat_name_local, ~, map] = get_file_names(app, true);
            load(mat_name_local)    
            
            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            display_spectra_zone(app, pixels_scans, map, limits, loud_data, mat_name_r)

        end

        % Button pushed function: PlotChromatogramButton
        function PlotChromatogramButtonPushed(app, event)
            if app.mzxml.is_loaded == 1
                figure()
                plot_chromatogram(app.mzxml.contents)
            else
                update_log(app, "No Mass Spectra file loaded : Impossible to plot chromatogram") % ERROR_MESSAGE
            end
        end

        % Value changed function: ResolutionmmEditField
        function ResolutionmmEditFieldValueChanged(app, event)
            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.PulsesperburstEditField.Value;
            time_step = app.TimebetweenbeamburstssEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
            app.EstimatedDurationLabel.Text = mapping_time_text;
            app.Resolutionm500Label.Text = strcat('Resolution(µm): ', num2str(step_size*1000));
        end

        % Value changed function: XmmEditField
        function XmmEditFieldValueChanged(app, event)
            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.PulsesperburstEditField.Value;
            time_step = app.TimebetweenbeamburstssEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
            app.EstimatedDurationLabel.Text = mapping_time_text;
            app.XmmEditField_2.Value = size_x;
        end

        % Value changed function: YmmEditField
        function YmmEditFieldValueChanged(app, event)
            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.PulsesperburstEditField.Value;
            time_step = app.TimebetweenbeamburstssEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
            app.EstimatedDurationLabel.Text = mapping_time_text;
            app.YmmEditField_2.Value = size_y;
        end

        % Value changed function: TimebetweenbeamburstssEditField
        function TimebetweenbeamburstssEditFieldValueChanged(app, event)
            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.PulsesperburstEditField.Value;
            time_step = app.TimebetweenbeamburstssEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Value changed function: PulsesperburstEditField
        function PulsesperburstEditFieldValueChanged(app, event)
            size_x = app.XmmEditField.Value;
            step_size = app.ResolutionmmEditField.Value;
            size_y = app.YmmEditField.Value;
            nb_shot = app.PulsesperburstEditField.Value;
            time_step = app.TimebetweenbeamburstssEditField.Value;

            [mapping_time_min, mapping_time_sec] = mapping_time_estimation(size_x,size_y,time_step,step_size,nb_shot);

            mapping_time_text = strcat('Estimation of mapping time :',32, num2str(mapping_time_min), ' min ',32,num2str(mapping_time_sec), '  sec ');
            app.EstimationofmappingtimeLabel.Text = mapping_time_text;
        end

        % Button pushed function: MapFromSpectraButton
        function MapFromSpectraButtonPushed(app, event)
            valmin = app.MinEditField.Value;
            valmax = app.MaxEditField.Value;

            update_log(app, string([valmin,valmax]))

            global all_peaks; % Variable to avoir reloading data each time it's diplayed (No reload if compute_flag == 1)
            global old_name;

            [mat_name_local, ~, map] = get_file_names(app, true);
            load(mat_name_local)    

            if length(mat_name_local) == length(old_name)
                if mat_name_local == old_name
                    compute_flag = 1;
                else
                    compute_flag = 0;
                end
            else
                compute_flag = 0;
            end

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            all_peaks = plot_all_spectra(app, pixels_scans, compute_flag, all_peaks, valmin, valmax);
            map_from_spectra(app, pixels_scans, map,loud_flag);

            old_name = mat_name_local;
        end

        % Button pushed function: LaunchManualRectificationButton
        function LaunchManualRectificationButtonPushed(app, event)
            map_pp_name_r = app.map_pp_name;

            check_isempty(app, map_pp_name_r,'.map')
            % fullfile % #TODO
            map_name_local = strcat(app.map_pp_path, map_pp_name_r, '.map');
            map = load_map_fcn(map_name_local);

            new_map_name_raw = app.NameofthecorrectedmapEditField.Value;
            new_map_name = strcat(new_map_name_raw,'.map');

            if strcmp(map_pp_name_r, new_map_name_raw) == 1
                answer = questdlg("The original map file will be overwitten unless you change the corrected map's name. Do you want to proceed?", "WARNING", "Yes", "No", "No");
                if answer == "No"
                    return 
                end
            end
            
            min_height_threshold = app.MinimumthresholdmmEditField.Value;
            max_height_threshold = app.MaximumthresholdmmEditField.Value;

            map_height = map.z;

            [corrected_map_height, nb_err]= map_rectification(map_height, min_height_threshold, max_height_threshold);

            map.z = recursive_topographic_correction(app, map, corrected_map_height, min_height_threshold, max_height_threshold);

            time_gap_median = record_map(map, new_map_name, app.map_path);
            
%           if isfield(map,'time')
%               time_diff_med = record_map(map.x,map.y,map_out,nom_map_new,map.time);
%           else
%               record_map_without_time(map.x,map.y,map_out,nom_map_new,threshold);
%           end
        end

        % Button pushed function: LaunchInterpolationButton
        function LaunchInterpolationButtonPushed(app, event)
            map_name_r = app.map_pp_name;
            map_name_local = strcat(app.map_pp_path, map_name_r, '.map');
            map = load_map_fcn(map_name_local);

            new_map_name_r = app.NameoftheinterpolatedmapEditField.Value;
            new_map_name = strcat(new_map_name_r,'.map');

            multiplier = app.PointMultiplierEditField.Value ;

            interpolated_map = polynomial_interpolation(map, multiplier);

            record_map(interpolated_map, new_map_name, app.map_pp_path);

        end

        % Button pushed function: LaunchLDAReconstructionButton
        function LaunchLDAReconstructionButtonPushed(app, event)
            [mat_name_local, map_name_local, ~] = get_file_names(app, false);
            map = load_map4_fcn(map_name_local);
            load(mat_name_local);

            csv_name_r = app.NameofthecsvfileEditField.Value;
            csv_path = strrep(app.mat_path, "mat_files", "csv_files");
            csv_name = strcat(csv_path, csv_name_r,'.csv');
            T = readtable(csv_name,'NumHeaderLines',5);  % skips the first three rows of data

            scan.num = T.StartScan;
            scan.num_end = T.EndScan;
            scan.class = T.ClassName;
            scan.prob = T.Probability;

            class(1) = string(app.Class01EditField.Value);
            class(2) = app.Class02EditField.Value;
            class(3) = app.Class03EditField.Value;
            class(4) = app.Class04EditField.Value;
            class(5) = app.Class05EditField.Value;
            class(6) = app.Class06EditField.Value;

            if isempty(class) == 0

                couleur_n(1) = string(app.ColorDropDown.Value);
                couleur_n(2) = app.ColorDropDown_2.Value;
                couleur_n(3) = app.ColorDropDown_3.Value;
                couleur_n(4) = app.ColorDropDown_4.Value;
                couleur_n(5) = app.ColorDropDown_5.Value;
                couleur_n(6) = app.ColorDropDown_6.Value;

                for i = 1 : length(couleur_n)
                    if couleur_n(i) == "Red"
                        color(i,:) = [0.9 0 0];
                    elseif couleur_n(i) == "Green"
                        color(i,:) = [0 0.8 0];
                    elseif couleur_n(i) == "Blue"
                        color(i,:) = [0 0 1];
                    elseif couleur_n(i) == "Orange"
                        color(i,:) = [1 0.5 0];
                    elseif couleur_n(i) == "Yellow"
                        color(i,:) = [0.9 0.9 0];
                    elseif couleur_n(i) == "Violet"
                        color(i,:) = [0.9 0 0.9];
                    elseif couleur_n(i) == "None"
                        color(i,:) = [0 0 0];
                    end
                 end

                if isfield(map,'time')
                    time_flag = 1;
                    map_time = map.time;
                else
                    time_flag = 0;
                    map_time = 0;
                end

                limits = [885 886]; % totalement arbitraire, ces valeurs ne sont pas utiles pour la suite

                [bio_ind, bio_num, ~, fusion_list] = data_on_map(bio_dat,map.z,limits,map_time,time_flag);
           
                si = size(map.z);
                bio_map_class = zeros(si(1),si(2),3);

                back_color = app.BackgroundColorDropDown.Value;
                if back_color == "White"
                    bio_map_class(:,:,:) = 1;
                elseif back_color == "Grey"
                    bio_map_class(:,:,:) = 0.5;
                end

                bio_map_class = bio_class_builder3(scan,bio_num,class,color,fusion_list,bio_ind,bio_map_class); % #TODO bio_class_builder ?

                [map.x, map.y, map.z, bio_map_class] = fix_border(map.x,map.y,map.z,bio_map_class,bio_ind);

                fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));
                figure()
                s = surf(map.x,map.y,map.z,bio_map_class);
                s.FaceAlpha = 0.9; % niveau de transparence
                s.FaceColor = 'flat'; % set color interpolation or not
                s.EdgeColor = 'none'; %'none' disable lines, you can also choose the color : 'white', etc.
                axis equal
                title('Biometric Class Map');
                legend(class);
            else
                msgbox("Please name at least one class to perform LDA.")
            end
        end

        % Button pushed function: mzXMLFileButton
        function mzXMLFileButtonPushed(app, event)
            [app.mzxml_name, app.mzxml_path] = uigetfile(".mzXML", "Select an mzXML file", "files\");
            app.mzxml_name = erase(app.mzxml_name, ".mzXML");
            
            update_log(app, "Loading the mzXML file...")
            
            mzxml_name_full = strcat(app.mzxml_path, app.mzxml_name,'.mzXML'); 
            
            app.mzxml.contents = mzxmlread_2019(mzxml_name_full);
            app.mzxml.is_loaded = 1;

            update_log(app, "mzXML file loaded!")

            if isempty(app.map_name) == 0 
                map_project = find_project(app, app.map_path);
                mzxml_project = find_project(app, app.mzxml_path);
                if strcmp(map_project, mzxml_project) == 1
                    app.MapFileButton_NewMat.FontColor = [0.0, 0.7, 0.0];
                    app.mzXMLFileButton.FontColor = [0.0, 0.7, 0.0];
                    mzxml_map_loaded(app);
                else
                    app.MapFileButton_NewMat.FontColor = [0.7, 0.3, 0.0];
                    app.mzXMLFileButton.FontColor = [0.7, 0.3, 0.0];
                    update_log(app, "Warning: The loaded map and mzXML " + ...
                        "files do not originate from the same project. " + ...
                        "Please chose files from the same project to proceed.")
                end
            else
                app.mzXMLFileButton.FontColor = [0.0, 0.7, 0.0];
            end            
           
            app.PlotChromatogramButton.Enable = "on";
            app.MapFileButton_NewMat.Enable = "on";
        end

        % Button pushed function: LaunchExtractionButton
        function LaunchExtractionButtonPushed(app, event)
            [mat_name_local, ~, ~] = get_file_names(app, false);
            load(mat_name_local);

            txt_name_r = app.map_name;
            txt_name = strcat(txt_name_r,'.txt');

            folder_txt = strrep(app.mat_path, "mat_files", "txt_files");
            txt_path  = strcat(folder_txt, txt_name);

            disp(txt_path)

            raw_name_r = app.InputWatersRAWFileEditField.Value;
            raw_name = strcat(raw_name_r,'.raw');           
            folder_raw = strrep(app.mat_path, "mat_files", "raw_files");
            raw_path  = strcat(folder_raw, raw_name);
            
            % disp(app.NeighbourFusionMultiplemzMLSwitch.Value)
            % disp(type(app.NeighbourFusionMultiplemzMLSwitch.Value))
            
            output_file_loc = strrep(app.mat_path, "mat_files", "mzML_files");

%             if isunix  % Light version of path_editor to accomodate the path leading to a folder instead of a file
%                 output_file_loc = strcat(app.workdir, "/files/conversion/");
%             else                
%                 output_file_loc = strcat(app.workdir, "\files\conversion\");
%             end

            multiple_flag = app.NeighbourFusionMultiplemzMLSwitch.Value;
            if multiple_flag(1:2) == 'On'

                multiple_mzML_generator(app, raw_path, folder_txt, pixels_scans, output_file_loc, app.mat_name)

            else

                l = length(pixels_scans);
                for i = 1 : l
                    num_list(i) = abs(pixels_scans(i).num);
                end

                export_to_txt(txt_path, num_list)
    
                % The txt file is written and saved
                   
    %           Data extraction continues in a Python script 
                % pyrunfile("code/code_python/raw_conversion/raw_conversion.py", txt_file = txt, raw_file=raw_folder, output_loc = output_file_loc)
    
    %           MatLab Replacement of the Python function
                raw_conversion(app, raw_path, txt_path, output_file_loc, app.mat_name)
                
            end

        end

        % Button pushed function: ScanSelectionButton
        function ScanSelectionButtonPushed(app, event)
             global method;
          %  global parameters; $useless ? #TODO
          %  Adel: I am in the process of replacing some of those with
          %  properties, which should provide a cleaner implementation

            [~, ~, map] = get_file_names(app, true);

            if isfield(map,'time')
                map.time = map.time;
            else
                map.time = 0;
            end

            fprintf('(%s) - Filtering the mzXML file to keep only the image related spectra\n', datestr(now,'HH:MM:SS'));

            method.type = app.ScanSelectionMethodDropDown.Value;

            method.class = eval(method.type);
            method.class.init(app) 
            
            % AspirationTimesEditField
            
            if isfield(app.mzxml,'contents')
                [pixels_scans, estimated_gap_time] =  method.class.selection(app.mzxml.contents, map.time, app); % Retrieve only useful informations
            else 
                message_error = "Warning: No mzXML file loaded. Cannot proceed with scan selection.";
                update_log(app, message_error)
                msgbox(message_error)
            end

            app.mzxml.pixels_scans = pixels_scans;
            
            app.GeneratedmatFileEditField.Enable = "on";    

            if app.ScanSelectionMethodDropDown.Value == "TimeBasedMethod"
                mat_name_local = strcat(app.mzxml_name, "_", num2str(app.AspirationTimesEditField.Value), "_", num2str(app.AspirationShiftsEditField.Value));
                app.GeneratedmatFileEditField.Value = erase(mat_name_local, ".");
            else
                app.GeneratedmatFileEditField.Value = app.map_name;
            end

            GeneratedmatFileEditFieldValueChanged(app, event)
            update_log(app, "Scan selection finished. You may now generate the .mat file")
        end

        % Button pushed function: ExporttoCSVButton
        function ExporttoCSVButtonPushed(app, event)
            mz_min = app.MinMzEditField.Value;
            mz_max = app.MaxMzEditField.Value;

            if mz_min < mz_max
                band = [mz_min, mz_max];
            else
                band = [mz_max, mz_min];
            end

            win = app.BinningEditField.Value;
            map = strcat(app.map_path, app.map_name, '.map');
            mat = strcat(app.mat_path, app.mat_name, '.mat');
            
            csv_extractor(app, map, mat, band, win)
        end

        % Button pushed function: NormaliseCSVButton
        function NormaliseCSVButtonPushed(app, event)
            norm_type = strcat(app.NormalizeCSVFilesDropDown.Value, '.py');
            pyrunfile(norm_type)
            update_log(app, "Done!")

            % #TODO: Change those three buttons to a dropdown menu with a
            % method
        end

        % Button pushed function: ExportfromCSVButton
        function ExportfromCSVButtonPushed(app, event)
            pyrunfile('CSV_to_PLY.py')
            update_log(app, "Done!")
        end

        % Button pushed function: ExportandcoregisterfromCSVButton
        function ExportandcoregisterfromCSVButtonPushed(app, event)
            pyrunfile('CSV_to_PNG.py')
            update_log(app, "Data converted to PNG. Proceeding with coregistration.")
            pyrunfile('2-Picture_Manual_Registration.py')
            update_log(app, "Coregistration complete. Now generating the final PLY file.")
            pyrunfile('CSV_to_PLY.py')
            update_log(app, "Done!")
        end

        % Button pushed function: ConnectSensorButton
        function ConnectSensorButtonPushed(app, event)
            global state;
            check_state(state, app);

            update_log(app, "Sensor Connexion... ")
            
            global sensor; % Or shut the connexion down
            global arduino_object

            sensor.type = app.SensorDropDown.Value;

            sensor.class = eval(sensor.type);

            sensor.class.init(arduino_object);

            update_log(app, "Sensor Connected ")

            app.LaunchSensorCalibrationButton.Enable = "on";
            app.SensorStateOffLabel.Text = "Sensor State: Connected";
            app.SensorStateOffLabel.FontColor = [1 0 0];
            app.SensorstateLamp.Color = [0 1 0];          
        end

        % Value changed function: ScanSelectionMethodDropDown
        function ScanSelectionMethodDropDownValueChanged(app, event)
             value = app.ScanSelectionMethodDropDown.Value;
             if strcmp(value, "TimeBasedMethod") == 1 % Direct comparison of strings cannot be done in certain MatLab versions
                 app.AspirationShiftsEditField.Enable = "on";
                 app.AspirationTimesEditField.Enable = "on";
                 app.StartThresholdEditField.Enable = "off"; 
                 app.FusionPercentageSlider.Enable = "off";
                 app.InternalTriggeringSwitch.Enable = "off";
                 app.NoiseThresholdEditField.Enable = "off";
                 app.NeighbouringScansEditField.Enable = "on";
                 app.TimebetweenburstssEditField.Enable = "off";
                 
             elseif strcmp(value, "PeakPickingMethod") == 1
                 app.AspirationShiftsEditField.Enable = "off";
                 app.AspirationTimesEditField.Enable = "off";
                 app.FusionPercentageSlider.Enable = "on";
                 app.InternalTriggeringSwitch.Enable = "on";
                 app.NoiseThresholdEditField.Enable = "on";
                 app.NeighbouringScansEditField.Enable = "off";
                 app.TimebetweenburstssEditField.Enable = "on";
                 InternalTriggeringSwitchValueChanged(app)                 
             end
        end

        % Value changed function: GeneratedmatFileEditField
        function GeneratedmatFileEditFieldValueChanged(app, event)
            value = app.GeneratedmatFileEditField.Value;
            app.mat_name = value;
            app.mat_path = strrep(app.mat_path, "mat_files", "map_files");

            if isempty(value) == 0
                app.BinningwindowsizeEditField.Enable = "on";
                app.GeneratematFileButton.Enable = "on";
            else
                app.BinningwindowsizeEditField.Enable = "off";
                app.GeneratematFileButton.Enable = "off";
            end
        end

        % Button pushed function: NewMatFileButton
        function NewMatFileButtonPushed(app, event)
% Causes janky window behaviour. This is probably because Matlab had the 
% brilliant idea to define positions from the bottom left corner, which 
% means we have to take the width and height into account to define a 
% position.    
            app.STORMMSIUIFigure.Visible = "off";
            app.TabGroup.Visible = "off";
            app.STORMMSIDataProcessingPanel.Visible = "off";

            app.STORMMSIUIFigure.Position = [app.STORMMSIUIFigure.Position(1),app.STORMMSIUIFigure.Position(2),603,500];
            app.STORMMSINewmatfilePanel.Position = [0,0,603,500];

            movegui(app.STORMMSIUIFigure, 'center')
            app.STORMMSINewmatfilePanel.Visible = "on";
            app.STORMMSIUIFigure.Visible = "on";
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.STORMMSINewmatfilePanel.Visible = "off";
            app.STORMMSIUIFigure.Visible = "off";
            app.STORMMSIUIFigure.Position = [app.STORMMSIUIFigure.Position(1), app.STORMMSIUIFigure.Position(2), 608, 800];
            app.STORMMSIDataProcessingPanel.Position = [0, 0, 608, 800];
            app.TabGroup.Position = [0, 0, 608, 723];

            movegui(app.STORMMSIUIFigure, 'center')
            app.STORMMSIDataProcessingPanel.Visible = "on";
            app.TabGroup.Visible = "on";
            app.STORMMSIUIFigure.Visible = "on";
        end

        % Button pushed function: QuittoMenuButton_2
        function QuittoMenuButton_2Pushed(app, event)
            app.TabGroup2.Visible = "off";
            app.STORMMSIUIFigure.Position = [100,100,263,220];
            movegui(app.STORMMSIUIFigure, 'center')
            app.STORMMSIModeSelectionPanel.Visible = "on";
        end

        % Button pushed function: QuittoMenuButton
        function QuittoMenuButtonPushed(app, event)
            app.TabGroup.Visible = "off";
            app.STORMMSIUIFigure.Position = [300,300,263,220];
            movegui(app.STORMMSIUIFigure, 'center')
            app.STORMMSIModeSelectionPanel.Visible = "on";
        end

        % Value changed function: NameofthecorrectedmapEditField
        function NameofthecorrectedmapEditFieldValueChanged(app, event)
            value = app.NameofthecorrectedmapEditField.Value;
            if isempty(value) == 0
                app.MinimumthresholdmmEditField.Enable = "on";
                app.MaximumthresholdmmEditField.Enable = "on";
                app.LaunchManualRectificationButton.Enable = "on"; 
                app.LaunchGaussianRectificationButton.Enable = "on";
            else
                app.MinimumthresholdmmEditField.Enable = "off";
                app.MaximumthresholdmmEditField.Enable = "off";
                app.LaunchManualRectificationButton.Enable = "off";
                app.LaunchGaussianRectificationButton.Enable = "off";
            end
        end

        % Value changed function: MinimumthresholdmmEditField
        function MinimumthresholdmmEditFieldValueChanged(app, event)
            value = app.MinimumthresholdmmEditField.Value;
            if isempty(value) == 0 && isempty(app.NameofthecorrectedmapEditField.Value) == 0 && isempty(app.MaximumthresholdmmEditField.Value) == 0
                app.LaunchManualRectificationButton.Enable = "on";
            else
                app.LaunchManualRectificationButton.Enable = "off";
            end
        end

        % Value changed function: MaximumthresholdmmEditField
        function MaximumthresholdmmEditFieldValueChanged(app, event)
            value = app.MaximumthresholdmmEditField.Value;
            if isempty(value) == 0 && isempty(app.NameofthecorrectedmapEditField.Value) == 0 && isempty(app.MinimumthresholdmmEditField.Value) == 0
                app.LaunchManualRectificationButton.Enable = "on";
            else
                app.LaunchManualRectificationButton.Enable = "off";
            end
        end

        % Value changed function: NameoftheinterpolatedmapEditField
        function NameoftheinterpolatedmapEditFieldValueChanged(app, event)
            value = app.NameoftheinterpolatedmapEditField.Value;
            if isempty(value) == 0
                app.PointMultiplierEditField.Enable = "on";
                if app.PointMultiplierEditField.Value <= 0
                    app.LaunchInterpolationButton.Enable = "off";
                else
                    app.LaunchInterpolationButton.Enable = "on";
                end
            else
                app.PointMultiplierEditField.Enable = "off";
                app.LaunchInterpolationButton.Enable = "off";
            end
        end

        % Value changed function: NameofthecsvfileEditField
        function NameofthecsvfileEditFieldValueChanged(app, event)
            value = app.NameofthecsvfileEditField.Value;
            if isempty(value) == 0
                app.Class01EditField.Enable = "on";
                app.Class02EditField.Enable = "on";
                app.Class03EditField.Enable = "on";
                app.Class04EditField.Enable = "on";
                app.Class05EditField.Enable = "on";
                app.Class06EditField.Enable = "on";
                app.ColorDropDown.Enable = "on";
                app.ColorDropDown_2.Enable = "on";
                app.ColorDropDown_3.Enable = "on";
                app.ColorDropDown_4.Enable = "on";
                app.ColorDropDown_5.Enable = "on";
                app.ColorDropDown_6.Enable = "on";
                app.BackgroundColorDropDown.Enable = "on";
                app.LaunchLDAReconstructionButton.Enable = "on";
            else
                app.Class01EditField.Enable = "off";
                app.Class02EditField.Enable = "off";
                app.Class03EditField.Enable = "off";
                app.Class04EditField.Enable = "off";
                app.Class05EditField.Enable = "off";
                app.Class06EditField.Enable = "off";
                app.ColorDropDown.Enable = "off";
                app.ColorDropDown_2.Enable = "off";
                app.ColorDropDown_3.Enable = "off";
                app.ColorDropDown_4.Enable = "off";
                app.ColorDropDown_5.Enable = "off";
                app.ColorDropDown_6.Enable = "off";
                app.BackgroundColorDropDown.Enable = "off";
                app.LaunchLDAReconstructionButton.Enable = "off";
            end
        end

        % Value changed function: PointMultiplierEditField
        function PointMultiplierEditFieldValueChanged(app, event)
            value = app.PointMultiplierEditField.Value;
            if value <= 0
                app.LaunchInterpolationButton.Enable = "off";
            else
                app.LaunchInterpolationButton.Enable = "on";
            end
        end

        % Value changed function: ContinuousAcquisitionCheckBox
        function ContinuousAcquisitionCheckBoxValueChanged(app, event)
            value = app.ContinuousAcquisitionCheckBox.Value;
            if value == 1
                app.TimebetweenbeamburstssEditField.Value = 0;
                app.PulsesperburstEditField.Enable = "off";
                XmmEditFieldValueChanged(app, event)
            else
                app.PulsesperburstEditField.Enable = "on";
            end
        end

        % Value changed function: GeneratedmapfileEditField
        function GeneratedmapfileEditFieldValueChanged(app, event)
%             TODO: CREATE SAFEGUARD TO AVOID MAP FILE OVERWRITING
            value = app.GeneratedmapfileEditField.Value;
            if isempty(value) == 0
                app.ResolutionmmEditField.Enable = "on";
                app.SurfaceoffsetmmEditField.Enable = "on";
                app.XmmEditField.Enable = "on";
                app.XmmEditField_2.Enable = "on";
                app.YmmEditField.Enable = "on";
                app.YmmEditField_2.Enable = "on";
                app.SampleMaximumHeightmmEditField.Enable = "on";
                app.FirstmappingpointXpositionmmEditField.Enable = "on";
                app.FirstmappingpointYpositionmmEditField.Enable = "on";
            else
                app.ResolutionmmEditField.Enable = "off";
                app.SurfaceoffsetmmEditField.Enable = "off";
                app.XmmEditField.Enable = "off";
                app.XmmEditField_2.Enable = "off";
                app.YmmEditField.Enable = "off";
                app.YmmEditField_2.Enable = "off";
                app.SampleMaximumHeightmmEditField.Enable = "off";
                app.FirstmappingpointXpositionmmEditField.Enable = "off";
                app.FirstmappingpointYpositionmmEditField.Enable = "off";
            end
            app.Label_2.Text = value;
        end

        % Selection changed function: MappingModeButtonGroup
        function MappingModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MappingModeButtonGroup.SelectedObject;
            if selectedButton == app.TopographyOnlyButton
                app.SourceDropDown.Enable = "off";
                app.ConnectSourceButton.Enable = "off";
                app.MappingModeTopographyOnlyLabel.Text = "Mapping Mode: Topography Only";
                app.SensorDropDown.Enable = "on";
                app.ConnectSensorButton.Enable = "on";
                app.ButtonGroupMappingSpeed.Enable = "on";

            elseif selectedButton == app.DMSIButton
                app.MappingModeTopographyOnlyLabel.Text = "Mapping Mode: 3D Mass Spectrometry";
                app.SensorDropDown.Enable = "on";
                app.ConnectSensorButton.Enable = "on";
                app.ButtonGroupMappingSpeed.Enable = "on";

            elseif selectedButton == app.DMSIButton_2
                app.MappingModeTopographyOnlyLabel.Text = "Mapping Mode: 2D Mass Spectrometry";
                app.SensorDropDown.Enable = "off";
                app.ConnectSensorButton.Enable = "off";
                app.ButtonGroupMappingSpeed.Enable = "off";
            end
        end

        % Button pushed function: DataProcessingButton_2
        function DataProcessingButton_2Pushed(app, event)
            app.STORMMSIModeSelectionPanel.Visible = "off";
            app.STORMMSIUIFigure.Visible = "off";
            app.STORMMSIUIFigure.Position = [app.STORMMSIUIFigure.Position(1), app.STORMMSIUIFigure.Position(2), 608, 800];
            app.STORMMSIDataProcessingPanel.Position = [0, 0, 608, 800];
            app.TabGroup.Position = [0, 0, 608, 723];

            movegui(app.STORMMSIUIFigure, 'center')
            app.STORMMSIDataProcessingPanel.Visible = "on";
            app.TabGroup.Visible = "on";
            app.STORMMSIUIFigure.Visible = "on";
            
        end

        % Button pushed function: DataAcquisitionButton_2
        function DataAcquisitionButton_2Pushed(app, event)
            app.STORMMSIModeSelectionPanel.Visible = "off";
            app.STORMMSIUIFigure.Position = [100, 100, 606, 490];
            app.TabGroup2.Position = [0, 0, 606, 490];
            movegui(app.STORMMSIUIFigure, 'center')
            app.TabGroup2.Visible = "on";
        end

        % Value changed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonValueChanged(app, event)
            global arduino_object;
            global robot;
            global source;
            global sensor;
            global state;         

%             if  app.EMERGENCYSTOPButton.Value % Why does this condition exist?
%                 emergency_stop(source, robot, sensor, state, arduino_object, app)
%             end

            emergency_stop(source, robot, sensor, state, arduino_object, app)
            check_state(state, app) % Restores proper diode states and 
            % enables buttons that facilitate hardware connexion     
            app.RobotConnexionB.Enable = "on";
            app.DisconnectRobotButton.Enable = "off";
        end

        % Button pushed function: DisplayAverageSpectrumButton
        function DisplayAverageSpectrumButtonPushed(app, event)
            [mat_name_local, ~, ~] = get_file_names(app, true);
            load(mat_name_local);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            display_spectra_sum(app, pixels_scans);
        end

        % Button pushed function: SinglePointSpectrumButton
        function SinglePointSpectrumButtonPushed(app, event)
            [mat_name_local, ~, map] = get_file_names(app, true);
            load(mat_name_local);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            record_one_spectra(app, pixels_scans, map, mat_name_local, limits, loud_data)
        end

        % Button pushed function: AverageSpectrumButton
        function AverageSpectrumButtonPushed(app, event)
            [mat_name_local, ~, ~] = get_file_names(app, true);
            load(mat_name_local);

            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            disp(mat_name_local)
            export_spectra_sum_csv(app, pixels_scans,mat_name_local);
        end

        % Button pushed function: AreaSpectrumButton
        function AreaSpectrumButtonPushed(app, event)
            [mat_name_local, ~, map] = get_file_names(app, true);
            load(mat_name_local);
            
            l_min = app.MasslimitsMinimumEditField.Value;
            l_max = app.MaximumEditField.Value;

            limits = [l_min l_max];

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            export_spectra_zone_csv(app, pixels_scans, map, limits,loud_data,mat_name_r)
        end

        % Button pushed function: LaunchReconstructionButton_RGB
        function LaunchReconstructionButton_RGBPushed(app, event)
            [mat_name_local, ~, map] = get_file_names(app, true);
            load(mat_name_local);

            l_min_R = app.MinimumEditField_R.Value;
            l_max_R = app.MaximumEditField_R.Value;
            limits_R = [l_min_R l_max_R];

            l_min_G = app.MinimumEditField_G.Value;
            l_max_G = app.MaximumEditField_G.Value;
            limits_G = [l_min_G l_max_G];

            l_min_B = app.MinimumEditField_B.Value;
            l_max_B = app.MaximumEditField_B.Value;
            limits_B = [l_min_B l_max_B];

            fprintf('(%s) - The map file is loaded \n', datestr(now,'HH:MM:SS'));
            fprintf('(%s) - Starting to build the intensity matrix\n', datestr(now,'HH:MM:SS'));

            loud_data = app.LouddataonthemapSwitch.Value;
            if loud_data(1:2) == 'On'
                loud_flag = 1;
            else
                loud_flag = 0;
            end

            if isfield(map,'time')
                time_flag = 1;
            else
                time_flag = 0;
                map_time = 0;
            end

            [ pixels_ind, scans_ind, pixels_mz_R, fusion_list] = data_on_map(app, pixels_scans,map,limits_R,map.time,time_flag,loud_flag);
            [ pixels_ind, scans_ind, pixels_mz_G, fusion_list] = data_on_map(app, pixels_scans,map,limits_G,map.time,time_flag,loud_flag);
            [ pixels_ind, scans_ind, pixels_mz_B, fusion_list] = data_on_map(app, pixels_scans,map,limits_B,map.time,time_flag,loud_flag);

            [ map_2.x,map_2.y,map_2.z,pixels_mz_R  ] = fix_border(map.x,map.y,map.z,pixels_mz_R,pixels_ind);
            [ map_2.x,map_2.y,map_2.z,pixels_mz_G  ] = fix_border(map.x,map.y,map.z,pixels_mz_G,pixels_ind);
            [ map.x,map.y,map.z,pixels_mz_B  ] = fix_border(map.x,map.y,map.z,pixels_mz_B,pixels_ind);

            fprintf('(%s) - Done, displaying the image\n', datestr(now,'HH:MM:SS'));
            
            display_RGB_mz_map(map,pixels_mz_R,pixels_mz_G,pixels_mz_B,limits_R,limits_G,limits_B)

        end

        % Value changed function: LaunchPCAButton
        function LaunchPCAButtonValueChanged(app, event)
        [mat_name_local, ~, ~] = get_file_names(app, true);
        load(mat_name_local);

        b1 = app.MinEditField.Value;
        b2 = app.MaxEditField.Value;
        band = [b1 b2];
        nb_point = 10; % v1 fixe, sinon app.NumberofpointonthecorrelationcirclemaximummzEditField.Value;
        win = 0.1; % app.BiningwindowmassEditField.Value;
        tlp = 1 ; %app.ShowalsoallmassesonanothercorrelationcircleCheckBox.Value;
        mat_pca_circle(win,band,nb_point,pixels_scans,tlp)


        end

        % Value changed function: InternalTriggeringSwitch
        function InternalTriggeringSwitchValueChanged(app, event)
            value = app.InternalTriggeringSwitch.Value;
            if value == "On"
                app.StartThresholdEditField.Enable = "off";
            else
                app.StartThresholdEditField.Enable = "on";
            end
        end

        % Value changed function: FocaldistancemmEditField_2
        function FocaldistancemmEditField_2ValueChanged(app, event)
            value = app.FocaldistancemmEditField_2.Value;
            app.FocaldistancemmEditField.Value = value;
        end

        % Value changed function: FocaldistancemmEditField
        function FocaldistancemmEditFieldValueChanged(app, event)
            value = app.FocaldistancemmEditField.Value;
            app.FocaldistancemmEditField_2.Value = value;
        end

        % Value changed function: XmmEditField_2
        function XmmEditField_2ValueChanged(app, event)
            size_x = app.XmmEditField_2.Value;
            app.XmmEditField.Value = size_x;
            XmmEditFieldValueChanged(app, event)
        end

        % Value changed function: YmmEditField_2
        function YmmEditField_2ValueChanged(app, event)
            size_y = app.YmmEditField_2.Value;
            app.YmmEditField.Value = size_y;
            YmmEditFieldValueChanged(app, event)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            robot.type = app.RobotDropDown.Value;
            robot.class = eval(robot.type);
            robot.class.reset_error(app);
        end

        % Value changed function: InputWatersRAWFileEditField
        function InputWatersRAWFileEditFieldValueChanged(app, event)
            value = app.InputWatersRAWFileEditField.Value;
            if isempty(value) == 0
                app.LaunchExtractionButton.Enable = "on";
                app.NeighbourFusionMultiplemzMLSwitch.Enable = "on";
            else 
                app.LaunchExtractionButton.Enable = "off";
                app.NeighbourFusionMultiplemzMLSwitch.Enable = "off";
            end
        end

        % Button pushed function: MatFileButton
        function MatFileButtonPushed(app, event)
            [app.mat_name, app.mat_path] = uigetfile(".mat", "Select a mat file", "files\");
            % TODO: Check whether it's worth keeping the extension in the name
            % property
            app.mat_name = erase(app.mat_name, ".mat");
            check_project(app)
        end

        % Button pushed function: MapFileButton
        function MapFileButtonPushed(app, event)
            [app.map_name, app.map_path] = uigetfile(".map", "Select a map file", "files\");
            % TODO: Check whether it's worth keeping the extension in the name
            % property
            app.map_name = erase(app.map_name, ".map");
            check_project(app)

            app.map_pp_name = app.map_name; % Updates the map used for topographical correction when a new map is loaded
            app.map_pp_path = app.map_path;
            update_pp_label(app)

            app.NameofthecorrectedmapEditField.Value = sprintf("%s-corrected", app.map_pp_name);
            NameofthecorrectedmapEditFieldValueChanged(app)
        end

        % Button pushed function: MapFileButton_NewMat
        function MapFileButton_NewMatPushed(app, event)
            MapFileButtonPushed(app, event)
            
            map_project = find_project(app, app.map_path);
            mzxml_project = find_project(app, app.mzxml_path);

            if strcmp(map_project, mzxml_project) == 1
                mzxml_map_loaded(app);
                app.MapFileButton_NewMat.FontColor = [0.0 0.7 0.0];
            else
                app.MapFileButton_NewMat.FontColor = [0.7 0.5 0.0];
                app.mzXMLFileButton.FontColor = [0.7 0.5 0.0];
                update_log(app, "Warning: The loaded map and mzXML " + ...
                        "files do not originate from the same project. " + ...
                        "Please chose files from the same project to proceed.")
                app.AspirationShiftsEditField.Enable = "off";
                app.AspirationTimesEditField.Enable = "off";
                app.StartThresholdEditField.Enable = "off";
                app.FusionPercentageSlider.Enable = "off";
                app.InternalTriggeringSwitch.Enable = "off";
                app.NoiseThresholdEditField.Enable = "off";
                app.NeighbouringScansEditField.Enable = "off";
                app.ScanSelectionButton.Enable = "off";
                app.ScanSelectionMethodDropDown.Enable = "off";
                app.TimebetweenburstssEditField.Enable = "off";
            end
        end

        % Button pushed function: MapFilePostProcessingButton
        function MapFilePostProcessingButtonPushed(app, event)
            [app.map_pp_name, app.map_pp_path] = uigetfile(".map", "Select a map file for topographical correction.", "files\");
            app.map_pp_name = erase(app.map_pp_name, ".map");
            update_pp_label(app)
        end

        % Button pushed function: LaunchGaussianRectificationButton
        function LaunchGaussianRectificationButtonPushed(app, event)
            map_pp_name_r = app.map_pp_name;

            check_isempty(app, map_pp_name_r,'.map')
            % fullfile % #TODO
            map_name_local = strcat(app.map_pp_path, map_pp_name_r, '.map');

            new_map_name_raw = app.NameofthecorrectedmapEditField.Value;
            new_map_name = strcat(new_map_name_raw,'.map');

            if strcmp(map_pp_name_r, new_map_name_raw) == 1
                answer = questdlg("The original map file will be overwitten unless you change the corrected map's name. Do you want to proceed?", "WARNING", "Yes", "No", "No");
                if answer == "No"
                    return 
                end
            end
            
            cmd_string = sprintf("Map_Gaussian_Headless.py %s %s", map_name_local, new_map_name);

            pyrunfile(cmd_string)
            msgbox("Done!")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.STORMMSIUIFigure = uifigure('Visible', 'off');
            app.STORMMSIUIFigure.Name = 'STORM-MSI';
            app.STORMMSIUIFigure.CloseRequestFcn = createCallbackFcn(app, @STORMMSIUIFigureCloseRequest, true);
            app.STORMMSIDataProcessingPanel = uipanel(app.STORMMSIUIFigure);
            app.STORMMSIDataProcessingPanel.Title = 'STORM-MSI - Data Processing';
            app.STORMMSIDataProcessingPanel.Visible = 'off';
            app.NewMatFileButton = uibutton(app.STORMMSIDataProcessingPanel, 'push');
            app.NewMatFileButton.ButtonPushedFcn = createCallbackFcn(app, @NewMatFileButtonPushed, true);
            app.NewMatFileButton.Tooltip = {'To create a new Mat file, please first convert your raw MS data to the mzXML format.'};
            app.NewMatFileButton.Text = 'New Mat File';
            app.QuittoMenuButton = uibutton(app.STORMMSIDataProcessingPanel, 'push');
            app.QuittoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @QuittoMenuButtonPushed, true);
            app.QuittoMenuButton.Text = 'Quit to Menu';
            app.StateLabel = uilabel(app.STORMMSIDataProcessingPanel);
            app.StateLabel.Text = 'Please input a valid mat and map file name to start processing!';
            app.MapFileButton = uibutton(app.STORMMSIDataProcessingPanel, 'push');
            app.MapFileButton.ButtonPushedFcn = createCallbackFcn(app, @MapFileButtonPushed, true);
            app.MapFileButton.Tooltip = {'Map file contain the spatial information of each point sampled during imaging. They are created once imaging is finished, and can be found in the map_files folder of their corresponding project.'};
            app.MapFileButton.Text = 'Map File';
            app.MatFileButton = uibutton(app.STORMMSIDataProcessingPanel, 'push');
            app.MatFileButton.ButtonPushedFcn = createCallbackFcn(app, @MatFileButtonPushed, true);
            app.MatFileButton.Tooltip = {'Mat files contain every scan retained after the scan selection process, and are used for data reconstruction. Create a Mat file using the "New Mat" button'};
            app.MatFileButton.Text = 'Mat File';
            app.STORMMSINewmatfilePanel = uipanel(app.STORMMSIUIFigure);
            app.STORMMSINewmatfilePanel.Title = 'STORM-MSI - New .mat file';
            app.STORMMSINewmatfilePanel.Visible = 'off';
            app.ExitButton = uibutton(app.STORMMSINewmatfilePanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.InputFilesLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.InputFilesLabel.Text = 'Input Files:';
            app.ScanSelectionLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.ScanSelectionLabel.Text = 'Scan Selection :';
            app.GeneratematFileButton = uibutton(app.STORMMSINewmatfilePanel, 'push');
            app.GeneratematFileButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratematFileButtonPushed, true);
            app.GeneratematFileButton.Enable = 'off';
            app.GeneratematFileButton.Text = 'Generate .mat File';
            app.BinningwindowsizeEditFieldLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.BinningwindowsizeEditFieldLabel.Text = 'Binning window size';
            app.BinningwindowsizeEditField = uieditfield(app.STORMMSINewmatfilePanel, 'numeric');
            app.BinningwindowsizeEditField.Enable = 'off';
            app.BinningwindowsizeEditField.Tooltip = {'Bins m/z intensities in the specified range'};
            app.BinningwindowsizeEditField.Value = 0.1;
            app.GeneratedmatFileEditFieldLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.GeneratedmatFileEditFieldLabel.Text = 'Generated .mat File ';
            app.GeneratedmatFileEditField = uieditfield(app.STORMMSINewmatfilePanel, 'text');
            app.GeneratedmatFileEditField.ValueChangedFcn = createCallbackFcn(app, @GeneratedmatFileEditFieldValueChanged, true);
            app.GeneratedmatFileEditField.Enable = 'off';
            app.GeneratedmatFileEditField.Tooltip = {'For time-based scan selection, we advise adding the aspiration time and shift at the end of the file''s name.'};
            app.matLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.matLabel.Text = '.mat';
            app.ScanSelectionButton = uibutton(app.STORMMSINewmatfilePanel, 'push');
            app.ScanSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ScanSelectionButtonPushed, true);
            app.ScanSelectionButton.Enable = 'off';
            app.ScanSelectionButton.Text = 'Scan Selection';
            app.InternalTriggeringSwitchLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.InternalTriggeringSwitchLabel.Text = 'Internal Triggering';
            app.InternalTriggeringSwitch = uiswitch(app.STORMMSINewmatfilePanel, 'slider');
            app.InternalTriggeringSwitch.ValueChangedFcn = createCallbackFcn(app, @InternalTriggeringSwitchValueChanged, true);
            app.InternalTriggeringSwitch.Enable = 'off';
            app.InternalTriggeringSwitch.Tooltip = {'If "No Triggering" was selected during the acquisition, turn this off. If disabled, the starting value for scan selection will be set through the "Begin Threshold" field.'};
            app.InternalTriggeringSwitch.Value = 'On';
            app.OutputLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.OutputLabel.Text = 'Output: ';
            app.FusionPercentageSliderLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.FusionPercentageSliderLabel.Text = ' Fusion Percentage';
            app.FusionPercentageSlider = uislider(app.STORMMSINewmatfilePanel);
            app.FusionPercentageSlider.Enable = 'off';
            app.FusionPercentageSlider.Tooltip = {'In peak picking, a fpercentage of the timestep is used to know whether peaks that are close to each other should be fused into one '};
            app.FusionPercentageSlider.Value = 50;
            app.AspirationTimesEditFieldLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.AspirationTimesEditFieldLabel.Text = 'Aspiration Time (s)';
            app.AspirationTimesEditField = uieditfield(app.STORMMSINewmatfilePanel, 'numeric');
            app.AspirationTimesEditField.Enable = 'off';
            app.AspirationTimesEditField.Tooltip = {'Fixed delay between sampling (times written in the .map file) and data detection on the detector (in the mzXMK file)'};
            app.AspirationTimesEditField.Value = 0.9;
            app.ScanSelectionMethodDropDownLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.ScanSelectionMethodDropDownLabel.Text = 'Scan Selection Method';
            app.ScanSelectionMethodDropDown = uidropdown(app.STORMMSINewmatfilePanel);
            app.ScanSelectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ScanSelectionMethodDropDownValueChanged, true);
            app.ScanSelectionMethodDropDown.Enable = 'off';
            app.ScanSelectionMethodDropDown.Tooltip = {'Methods for assigning scans to points in the map file. There are currently two methods: '; '- Peak Picking: Peak detection that allows for finer scan assignment. Advised for conventional, "discrete" acquisitions, where a pause is taken between individual sampling points, allowing the chromatogram to return to a baseline.'; '- Time-Based: Alternative scan selection method, primarily intended for continuous analyses where peak picking cannot be successfuly performed.'};
            app.mzXMLFileButton = uibutton(app.STORMMSINewmatfilePanel, 'push');
            app.mzXMLFileButton.ButtonPushedFcn = createCallbackFcn(app, @mzXMLFileButtonPushed, true);
            app.mzXMLFileButton.Text = 'mzXML File';
            app.PlotChromatogramButton = uibutton(app.STORMMSINewmatfilePanel, 'push');
            app.PlotChromatogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotChromatogramButtonPushed, true);
            app.PlotChromatogramButton.Enable = 'off';
            app.PlotChromatogramButton.Text = 'Plot Chromatogram';
            app.StartThresholdEditFieldLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.StartThresholdEditFieldLabel.Text = 'Start Threshold';
            app.StartThresholdEditField = uieditfield(app.STORMMSINewmatfilePanel, 'numeric');
            app.StartThresholdEditField.Enable = 'off';
            app.StartThresholdEditField.Value = 10000;
            app.TimebetweenburstssEditFieldLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.TimebetweenburstssEditFieldLabel.Text = 'Time between bursts (s):';
            app.TimebetweenburstssEditField = uieditfield(app.STORMMSINewmatfilePanel, 'numeric');
            app.TimebetweenburstssEditField.Enable = 'off';
            app.TimebetweenburstssEditField.Value = 1.5;
            app.LogTextAreaLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.STORMMSINewmatfilePanel);
            app.LogTextArea.Editable = 'off';
            app.NoiseThresholdEditFieldLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.NoiseThresholdEditFieldLabel.Text = 'Noise Threshold';
            app.NoiseThresholdEditField = uieditfield(app.STORMMSINewmatfilePanel, 'numeric');
            app.NoiseThresholdEditField.Enable = 'off';
            app.NoiseThresholdEditField.Value = 1000;
            app.NeighbouringScansEditFieldLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.NeighbouringScansEditFieldLabel.Text = 'Neighbouring Scans';
            app.NeighbouringScansEditField = uieditfield(app.STORMMSINewmatfilePanel, 'numeric');
            app.NeighbouringScansEditField.Enable = 'off';
            app.AspirationShiftsEditFieldLabel = uilabel(app.STORMMSINewmatfilePanel);
            app.AspirationShiftsEditFieldLabel.Text = 'Aspiration Shift (s)';
            app.AspirationShiftsEditField = uieditfield(app.STORMMSINewmatfilePanel, 'numeric');
            app.AspirationShiftsEditField.Enable = 'off';
            app.AspirationShiftsEditField.Tooltip = {'Linear increase of the time correction throughout the image. '};
            app.MapFileButton_NewMat = uibutton(app.STORMMSINewmatfilePanel, 'push');
            app.MapFileButton_NewMat.ButtonPushedFcn = createCallbackFcn(app, @MapFileButton_NewMatPushed, true);
            app.MapFileButton_NewMat.Enable = 'off';
            app.MapFileButton_NewMat.Text = 'Map File';
            app.TabGroup = uitabgroup(app.STORMMSIUIFigure);
            app.TabGroup.Visible = 'off';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.MasslimitsMinimumEditFieldLabel = uilabel(app.ProcessingTab);
            app.MasslimitsMinimumEditFieldLabel.Text = 'Mass limits:    Minimum:';
            app.MasslimitsMinimumEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MasslimitsMinimumEditField.ValueDisplayFormat = '%.3f';
            app.MasslimitsMinimumEditField.ValueChangedFcn = createCallbackFcn(app, @MasslimitsMinimumEditFieldValueChanged, true);
            app.MasslimitsMinimumEditField.Enable = 'off';
            app.MasslimitsMinimumEditField.Value = 600;
            app.MaximumEditFieldLabel = uilabel(app.ProcessingTab);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MaximumEditField.ValueDisplayFormat = '%.3f';
            app.MaximumEditField.Enable = 'off';
            app.MaximumEditField.Value = 600.25;
            app.LaunchReconstructionButton = uibutton(app.ProcessingTab, 'push');
            app.LaunchReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchReconstructionButtonPushed, true);
            app.LaunchReconstructionButton.Enable = 'off';
            app.LaunchReconstructionButton.Text = 'Launch Reconstruction';
            app.DisplayOneSpectrumButton = uibutton(app.ProcessingTab, 'push');
            app.DisplayOneSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayOneSpectrumButtonPushed, true);
            app.DisplayOneSpectrumButton.Enable = 'off';
            app.DisplayOneSpectrumButton.Text = 'Display One Spectrum';
            app.DisplayAreaSpectrumButton = uibutton(app.ProcessingTab, 'push');
            app.DisplayAreaSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayAreaSpectrumButtonPushed, true);
            app.DisplayAreaSpectrumButton.Enable = 'off';
            app.DisplayAreaSpectrumButton.Text = 'Display Area Spectrum';
            app.MapFromSpectraButton = uibutton(app.ProcessingTab, 'push');
            app.MapFromSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @MapFromSpectraButtonPushed, true);
            app.MapFromSpectraButton.Enable = 'off';
            app.MapFromSpectraButton.Tooltip = {'Allows to rebuild images by selecting a mass range from the average spectrum. Note that in order to build a subsequent image, the chromatogram must be closed, and this button musst be pressed again.'};
            app.MapFromSpectraButton.Text = 'Map From Spectra';
            app.MaxEditFieldLabel = uilabel(app.ProcessingTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MaxEditField.Enable = 'off';
            app.MaxEditField.Value = 2000;
            app.MinEditFieldLabel = uilabel(app.ProcessingTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MinEditField.Enable = 'off';
            app.DataReconstructionLabel = uilabel(app.ProcessingTab);
            app.DataReconstructionLabel.Text = 'Data Reconstruction :';
            app.AdditionnalDataExtractionFeaturesLabel = uilabel(app.ProcessingTab);
            app.AdditionnalDataExtractionFeaturesLabel.Text = 'Additionnal Data Extraction Features :';
            app.LouddataonthemapSwitchLabel = uilabel(app.ProcessingTab);
            app.LouddataonthemapSwitchLabel.Text = 'Loud data on the map';
            app.LouddataonthemapSwitch = uiswitch(app.ProcessingTab, 'slider');
            app.LouddataonthemapSwitch.Enable = 'off';
            app.LouddataonthemapSwitch.Tooltip = {'In peak picking, some points are added to fill the gaps in where there is no signal. When loud data is turned off, those ''''fake points'' '''' data are not taken into account.'};
            app.DisplayAverageSpectrumButton = uibutton(app.ProcessingTab, 'push');
            app.DisplayAverageSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayAverageSpectrumButtonPushed, true);
            app.DisplayAverageSpectrumButton.Enable = 'off';
            app.DisplayAverageSpectrumButton.Text = 'Display Average Spectrum';
            app.LaunchReconstructionButton_RGB = uibutton(app.ProcessingTab, 'push');
            app.LaunchReconstructionButton_RGB.ButtonPushedFcn = createCallbackFcn(app, @LaunchReconstructionButton_RGBPushed, true);
            app.LaunchReconstructionButton_RGB.Enable = 'off';
            app.LaunchReconstructionButton_RGB.Text = 'Launch Reconstruction';
            app.MinimumEditFieldLabel = uilabel(app.ProcessingTab);
            app.MinimumEditFieldLabel.Text = '   Minimum:';
            app.MinimumEditField_R = uieditfield(app.ProcessingTab, 'numeric');
            app.MinimumEditField_R.ValueDisplayFormat = '%.3f';
            app.MinimumEditField_R.Enable = 'off';
            app.MinimumEditField_R.Value = 600;
            app.MaximumEditField_2Label = uilabel(app.ProcessingTab);
            app.MaximumEditField_2Label.Text = 'Maximum';
            app.MaximumEditField_R = uieditfield(app.ProcessingTab, 'numeric');
            app.MaximumEditField_R.ValueDisplayFormat = '%.3f';
            app.MaximumEditField_R.Enable = 'off';
            app.MaximumEditField_R.Value = 600.25;
            app.RGBDisplayLabel = uilabel(app.ProcessingTab);
            app.RGBDisplayLabel.Text = 'RGB Display :';
            app.RedLabel = uilabel(app.ProcessingTab);
            app.RedLabel.Text = 'Red :';
            app.GreenLabel = uilabel(app.ProcessingTab);
            app.GreenLabel.Text = 'Green :';
            app.MinimumEditField_2Label = uilabel(app.ProcessingTab);
            app.MinimumEditField_2Label.Text = '   Minimum:';
            app.MinimumEditField_G = uieditfield(app.ProcessingTab, 'numeric');
            app.MinimumEditField_G.ValueDisplayFormat = '%.3f';
            app.MinimumEditField_G.Enable = 'off';
            app.MinimumEditField_G.Value = 600;
            app.MaximumEditField_3Label = uilabel(app.ProcessingTab);
            app.MaximumEditField_3Label.Text = 'Maximum';
            app.MaximumEditField_G = uieditfield(app.ProcessingTab, 'numeric');
            app.MaximumEditField_G.ValueDisplayFormat = '%.3f';
            app.MaximumEditField_G.Enable = 'off';
            app.MaximumEditField_G.Value = 600.25;
            app.BlueLabel = uilabel(app.ProcessingTab);
            app.BlueLabel.Text = 'Blue :';
            app.MinimumEditField_3Label = uilabel(app.ProcessingTab);
            app.MinimumEditField_3Label.Text = '   Minimum:';
            app.MinimumEditField_B = uieditfield(app.ProcessingTab, 'numeric');
            app.MinimumEditField_B.ValueDisplayFormat = '%.3f';
            app.MinimumEditField_B.Enable = 'off';
            app.MinimumEditField_B.Value = 600;
            app.MaximumEditField_4Label = uilabel(app.ProcessingTab);
            app.MaximumEditField_4Label.Text = 'Maximum';
            app.MaximumEditField_B = uieditfield(app.ProcessingTab, 'numeric');
            app.MaximumEditField_B.ValueDisplayFormat = '%.3f';
            app.MaximumEditField_B.Enable = 'off';
            app.MaximumEditField_B.Value = 600.25;
            app.LaunchPCAButton = uibutton(app.ProcessingTab, 'state');
            app.LaunchPCAButton.ValueChangedFcn = createCallbackFcn(app, @LaunchPCAButtonValueChanged, true);
            app.LaunchPCAButton.Enable = 'off';
            app.LaunchPCAButton.Text = 'Launch PCA';
            app.PrincipalComponentAnalysisLabel = uilabel(app.ProcessingTab);
            app.PrincipalComponentAnalysisLabel.Text = 'Principal Component Analysis : ';
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post Processing';
            app.NameofthecorrectedmapEditFieldLabel = uilabel(app.PostProcessingTab);
            app.NameofthecorrectedmapEditFieldLabel.Text = 'Name of the corrected map ';
            app.NameofthecorrectedmapEditField = uieditfield(app.PostProcessingTab, 'text');
            app.NameofthecorrectedmapEditField.ValueChangedFcn = createCallbackFcn(app, @NameofthecorrectedmapEditFieldValueChanged, true);
            app.NameofthecorrectedmapEditField.Enable = 'off';
            app.LaunchManualRectificationButton = uibutton(app.PostProcessingTab, 'push');
            app.LaunchManualRectificationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchManualRectificationButtonPushed, true);
            app.LaunchManualRectificationButton.Enable = 'off';
            app.LaunchManualRectificationButton.Tooltip = {'Automatically recomputes the height of points falling outside of the threshold based on the height of neighbouring points. Manual selection of points to correct is also possible. '};
            app.LaunchManualRectificationButton.Text = 'Launch Manual Rectification ';
            app.mapLabel_4 = uilabel(app.PostProcessingTab);
            app.mapLabel_4.Text = '.map';
            app.MinimumthresholdmmEditFieldLabel = uilabel(app.PostProcessingTab);
            app.MinimumthresholdmmEditFieldLabel.Text = 'Minimum threshold (mm) ';
            app.MinimumthresholdmmEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.MinimumthresholdmmEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumthresholdmmEditFieldValueChanged, true);
            app.MinimumthresholdmmEditField.Enable = 'off';
            app.MinimumthresholdmmEditField.Value = -1;
            app.MaximumthresholdmmEditFieldLabel = uilabel(app.PostProcessingTab);
            app.MaximumthresholdmmEditFieldLabel.Text = 'Maximum threshold (mm) ';
            app.MaximumthresholdmmEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.MaximumthresholdmmEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumthresholdmmEditFieldValueChanged, true);
            app.MaximumthresholdmmEditField.Enable = 'off';
            app.MaximumthresholdmmEditField.Value = 20;
            app.LaunchInterpolationButton = uibutton(app.PostProcessingTab, 'push');
            app.LaunchInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchInterpolationButtonPushed, true);
            app.LaunchInterpolationButton.Enable = 'off';
            app.LaunchInterpolationButton.Text = 'Launch Interpolation';
            app.mapLabel_7 = uilabel(app.PostProcessingTab);
            app.mapLabel_7.Text = '.map';
            app.NameoftheinterpolatedmapEditFieldLabel = uilabel(app.PostProcessingTab);
            app.NameoftheinterpolatedmapEditFieldLabel.Text = 'Name of the interpolated map ';
            app.NameoftheinterpolatedmapEditField = uieditfield(app.PostProcessingTab, 'text');
            app.NameoftheinterpolatedmapEditField.ValueChangedFcn = createCallbackFcn(app, @NameoftheinterpolatedmapEditFieldValueChanged, true);
            app.NameoftheinterpolatedmapEditField.Enable = 'off';
            app.NameoftheinterpolatedmapEditField.Tooltip = {'Ploynomial interpolation of the map file. Helps smoothing a map''s features.'};
            app.PointMultiplierEditFieldLabel = uilabel(app.PostProcessingTab);
            app.PointMultiplierEditFieldLabel.Text = 'Point Multiplier';
            app.PointMultiplierEditField = uieditfield(app.PostProcessingTab, 'numeric');
            app.PointMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @PointMultiplierEditFieldValueChanged, true);
            app.PointMultiplierEditField.Enable = 'off';
            app.PointMultiplierEditField.Value = 2;
            app.TopographicalCorrectionLabel = uilabel(app.PostProcessingTab);
            app.TopographicalCorrectionLabel.Text = 'Topographical Correction';
            app.TopographicalInterpolationLabel = uilabel(app.PostProcessingTab);
            app.TopographicalInterpolationLabel.Text = 'Topographical Interpolation';
            app.MapFilePostProcessingButton = uibutton(app.PostProcessingTab, 'push');
            app.MapFilePostProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @MapFilePostProcessingButtonPushed, true);
            app.MapFilePostProcessingButton.Enable = 'off';
            app.MapFilePostProcessingButton.Text = {'Map File'; 'Post-Processing'};
            app.MapNameNoneLabel = uilabel(app.PostProcessingTab);
            app.MapNameNoneLabel.Text = 'Map Name: None';
            app.LaunchGaussianRectificationButton = uibutton(app.PostProcessingTab, 'push');
            app.LaunchGaussianRectificationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchGaussianRectificationButtonPushed, true);
            app.LaunchGaussianRectificationButton.Enable = 'off';
            app.LaunchGaussianRectificationButton.Tooltip = {'Automatically recomputes the height of points falling outside of the threshold based on the height of neighbouring points. Manual selection of points to correct is also possible. '};
            app.LaunchGaussianRectificationButton.Text = 'Launch Gaussian Rectification ';
            app.DisplayfromexternaldataTab = uitab(app.TabGroup);
            app.DisplayfromexternaldataTab.Title = 'Display from external data';
            app.ColorDropDownLabel = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown.Enable = 'off';
            app.ColorDropDown.Value = 'Red';
            app.csvLabel = uilabel(app.DisplayfromexternaldataTab);
            app.csvLabel.Text = '.csv';
            app.NameofthecsvfileEditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.NameofthecsvfileEditFieldLabel.Text = 'Name of the csv file';
            app.NameofthecsvfileEditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.NameofthecsvfileEditField.ValueChangedFcn = createCallbackFcn(app, @NameofthecsvfileEditFieldValueChanged, true);
            app.Class01EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class01EditFieldLabel.Text = 'Class 01';
            app.Class01EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class01EditField.Enable = 'off';
            app.Class02EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class02EditFieldLabel.Text = 'Class 02';
            app.Class02EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class02EditField.Enable = 'off';
            app.Class03EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class03EditFieldLabel.Text = 'Class 03';
            app.Class03EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class03EditField.Enable = 'off';
            app.LaunchLDAReconstructionButton = uibutton(app.DisplayfromexternaldataTab, 'push');
            app.LaunchLDAReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchLDAReconstructionButtonPushed, true);
            app.LaunchLDAReconstructionButton.Enable = 'off';
            app.LaunchLDAReconstructionButton.Text = 'Launch LDA Reconstruction';
            app.BackgroundColorDropDownLabel = uilabel(app.DisplayfromexternaldataTab);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.DisplayfromexternaldataTab);
            app.BackgroundColorDropDown.Items = {'Black', 'White', 'Grey'};
            app.BackgroundColorDropDown.Enable = 'off';
            app.BackgroundColorDropDown.Value = 'Black';
            app.ColorDropDown_2Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_2.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_2.Enable = 'off';
            app.ColorDropDown_2.Value = 'Green';
            app.ColorDropDown_3Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_3Label.Text = 'Color';
            app.ColorDropDown_3 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_3.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_3.Enable = 'off';
            app.ColorDropDown_3.Value = 'Orange';
            app.ColorDropDown_4Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_4Label.Text = 'Color';
            app.ColorDropDown_4 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_4.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_4.Enable = 'off';
            app.ColorDropDown_4.Value = 'None';
            app.Class04EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class04EditFieldLabel.Text = 'Class 04';
            app.Class04EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class04EditField.Enable = 'off';
            app.Class05EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class05EditFieldLabel.Text = 'Class 05';
            app.Class05EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class05EditField.Enable = 'off';
            app.Class06EditFieldLabel = uilabel(app.DisplayfromexternaldataTab);
            app.Class06EditFieldLabel.Text = 'Class 06';
            app.Class06EditField = uieditfield(app.DisplayfromexternaldataTab, 'text');
            app.Class06EditField.Enable = 'off';
            app.ColorDropDown_5Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_5Label.Text = 'Color';
            app.ColorDropDown_5 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_5.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_5.Enable = 'off';
            app.ColorDropDown_5.Value = 'None';
            app.ColorDropDown_6Label = uilabel(app.DisplayfromexternaldataTab);
            app.ColorDropDown_6Label.Text = 'Color';
            app.ColorDropDown_6 = uidropdown(app.DisplayfromexternaldataTab);
            app.ColorDropDown_6.Items = {'Red', 'Green', 'Blue', 'Orange', 'Violet', 'Yellow', 'None'};
            app.ColorDropDown_6.Enable = 'off';
            app.ColorDropDown_6.Value = 'None';
            app.Label = uilabel(app.DisplayfromexternaldataTab);
            app.Label.Text = 'Import Classification from External Software : Display of the classification on the map';
            app.Scils_Extraction = uitab(app.TabGroup);
            app.Scils_Extraction.Title = 'Data Extraction';
            app.LaunchExtractionButton = uibutton(app.Scils_Extraction, 'push');
            app.LaunchExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchExtractionButtonPushed, true);
            app.LaunchExtractionButton.Enable = 'off';
            app.LaunchExtractionButton.Text = 'Launch Extraction';
            app.ExporttomzMLLabel = uilabel(app.Scils_Extraction);
            app.ExporttomzMLLabel.Text = 'Export to mzML';
            app.Exportto3DfilePLYfromCSVLabel = uilabel(app.Scils_Extraction);
            app.Exportto3DfilePLYfromCSVLabel.Text = 'Export to 3D file (PLY) from CSV';
            app.ExporttoCSVFullExperimentLabel = uilabel(app.Scils_Extraction);
            app.ExporttoCSVFullExperimentLabel.Text = 'Export to CSV (Full Experiment)';
            app.MinMzEditFieldLabel = uilabel(app.Scils_Extraction);
            app.MinMzEditFieldLabel.Text = 'Min M/z';
            app.MinMzEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.MinMzEditField.Enable = 'off';
            app.MaxMzEditFieldLabel = uilabel(app.Scils_Extraction);
            app.MaxMzEditFieldLabel.Text = 'Max M/z';
            app.MaxMzEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.MaxMzEditField.Enable = 'off';
            app.MaxMzEditField.Value = 2000;
            app.BinningEditFieldLabel = uilabel(app.Scils_Extraction);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.Scils_Extraction, 'numeric');
            app.BinningEditField.Enable = 'off';
            app.BinningEditField.Value = 0.1;
            app.ExporttoCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExporttoCSVButton.Enable = 'off';
            app.ExporttoCSVButton.Text = 'Export to CSV';
            app.NormaliseCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.NormaliseCSVButton.ButtonPushedFcn = createCallbackFcn(app, @NormaliseCSVButtonPushed, true);
            app.NormaliseCSVButton.Tooltip = {'Takes a .csv file generated with the "Export to CSV" button, and performs Total Ion Count (TIC) normalization on it. Highly recommended for data interpretation.'};
            app.NormaliseCSVButton.Text = 'Normalise CSV';
            app.ExportfromCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportfromCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfromCSVButtonPushed, true);
            app.ExportfromCSVButton.Text = 'Export from CSV';
            app.ExportandcoregisterfromCSVButton = uibutton(app.Scils_Extraction, 'push');
            app.ExportandcoregisterfromCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportandcoregisterfromCSVButtonPushed, true);
            app.ExportandcoregisterfromCSVButton.Text = 'Export and coregister from CSV';
            app.SinglePointSpectrumButton = uibutton(app.Scils_Extraction, 'push');
            app.SinglePointSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePointSpectrumButtonPushed, true);
            app.SinglePointSpectrumButton.Text = 'Single Point Spectrum';
            app.AverageSpectrumButton = uibutton(app.Scils_Extraction, 'push');
            app.AverageSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @AverageSpectrumButtonPushed, true);
            app.AverageSpectrumButton.Text = 'Average Spectrum';
            app.AreaSpectrumButton = uibutton(app.Scils_Extraction, 'push');
            app.AreaSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @AreaSpectrumButtonPushed, true);
            app.AreaSpectrumButton.Text = 'Area Spectrum';
            app.RAWLabel = uilabel(app.Scils_Extraction);
            app.RAWLabel.Text = '.RAW';
            app.InputWatersRAWFileEditFieldLabel = uilabel(app.Scils_Extraction);
            app.InputWatersRAWFileEditFieldLabel.Text = 'Input Waters RAW File:';
            app.InputWatersRAWFileEditField = uieditfield(app.Scils_Extraction, 'text');
            app.InputWatersRAWFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputWatersRAWFileEditFieldValueChanged, true);
            app.InputWatersRAWFileEditField.Enable = 'off';
            app.InputWatersRAWFileEditField.Tooltip = {'Note: The .RAW file must be placed in "OMG_MSI/files/raw files". This step currently only supports MS data in the Waters RAW file format.'};
            app.NeighbourFusionMultiplemzMLSwitchLabel = uilabel(app.Scils_Extraction);
            app.NeighbourFusionMultiplemzMLSwitchLabel.Text = 'Neighbour Fusion (Multiple mzML)';
            app.NeighbourFusionMultiplemzMLSwitch = uiswitch(app.Scils_Extraction, 'slider');
            app.NeighbourFusionMultiplemzMLSwitch.Enable = 'off';
            app.NeighbourFusionMultiplemzMLSwitch.Tooltip = {'Will export every point as its own mzML file. This can help mitigate issues in the imZML conversion where identical pixels are disregarded'};
            app.NormalizeCSVFilesDropDownLabel = uilabel(app.Scils_Extraction);
            app.NormalizeCSVFilesDropDownLabel.Text = 'Normalize CSV Files';
            app.NormalizeCSVFilesDropDown = uidropdown(app.Scils_Extraction);
            app.ExporttoCSVSpectrumLabel = uilabel(app.Scils_Extraction);
            app.ExporttoCSVSpectrumLabel.Text = 'Export to CSV (Spectrum)';
            app.LogTab_2 = uitab(app.TabGroup);
            app.LogTab_2.Title = 'Log';
            app.TextArea2Label = uilabel(app.LogTab_2);
            app.TextArea2Label.Enable = 'off';
            app.TextArea2Label.Visible = 'off';
            app.TextArea2Label.Text = 'Text Area2';
            app.TextArea2 = uitextarea(app.LogTab_2);
            app.TabGroup2 = uitabgroup(app.STORMMSIUIFigure);
            app.TabGroup2.Visible = 'off';
            app.ModeTab = uitab(app.TabGroup2);
            app.ModeTab.Title = 'Mode';
            app.MappingModeButtonGroup = uibuttongroup(app.ModeTab);
            app.MappingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MappingModeButtonGroupSelectionChanged, true);
            app.MappingModeButtonGroup.Title = 'Mapping Mode';
            app.TopographyOnlyButton = uiradiobutton(app.MappingModeButtonGroup);
            app.TopographyOnlyButton.Text = 'Topography Only';
            app.TopographyOnlyButton.Value = true;
            app.DMSIButton = uiradiobutton(app.MappingModeButtonGroup);
            app.DMSIButton.Tooltip = {'Mass Spectrometry Imaging of a non-flat sample. Requires the use of a topographical sensor.'};
            app.DMSIButton.Text = '3D MSI';
            app.LampOnlyTopo = uilamp(app.MappingModeButtonGroup);
            app.LampOnlyTopo.Tooltip = {'Those lamps are most likely getting removed'};
            app.LampWithSpectro = uilamp(app.MappingModeButtonGroup);
            app.DMSIButton_2 = uiradiobutton(app.MappingModeButtonGroup);
            app.DMSIButton_2.Text = '2D MSI';
            app.LampWithSpectro_2D = uilamp(app.MappingModeButtonGroup);
            app.ButtonGroupMappingSpeed = uibuttongroup(app.ModeTab);
            app.ButtonGroupMappingSpeed.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupMappingSpeedSelectionChanged, true);
            app.ButtonGroupMappingSpeed.Tooltip = {'If enabled, the robot will reposition in height in the case of a wrong depth measurement before moving on to the next sampling position. Can slow the acquisition down.'};
            app.ButtonGroupMappingSpeed.Title = 'Topographical Correction';
            app.EnabledButton = uiradiobutton(app.ButtonGroupMappingSpeed);
            app.EnabledButton.Tooltip = {'Implements an error correction routine in the robot movement, at the cost of a longer experiment. Can be detrimental at high acquisition speeds.'};
            app.EnabledButton.Text = 'Enabled';
            app.EnabledButton.Value = true;
            app.DisabledButton = uiradiobutton(app.ButtonGroupMappingSpeed);
            app.DisabledButton.Tooltip = {'Shunts the repositioning routine when the arm is misplaced in height..'};
            app.DisabledButton.Text = 'Disabled';
            app.PropertiesTab = uitab(app.TabGroup2);
            app.PropertiesTab.Title = 'Properties';
            app.GeneratedmapfileEditFieldLabel = uilabel(app.PropertiesTab);
            app.GeneratedmapfileEditFieldLabel.Text = 'Generated map file';
            app.GeneratedmapfileEditField = uieditfield(app.PropertiesTab, 'text');
            app.GeneratedmapfileEditField.ValueChangedFcn = createCallbackFcn(app, @GeneratedmapfileEditFieldValueChanged, true);
            app.mapLabel = uilabel(app.PropertiesTab);
            app.mapLabel.Text = '.map';
            app.ResolutionmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.ResolutionmmEditFieldLabel.Text = 'Resolution  (mm)';
            app.ResolutionmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.ResolutionmmEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionmmEditFieldValueChanged, true);
            app.ResolutionmmEditField.Enable = 'off';
            app.ResolutionmmEditField.Value = 0.5;
            app.SurfaceoffsetmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.SurfaceoffsetmmEditFieldLabel.Text = 'Surface offset (mm)';
            app.SurfaceoffsetmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.SurfaceoffsetmmEditField.Enable = 'off';
            app.XmmLabel = uilabel(app.PropertiesTab);
            app.XmmLabel.Text = '- X (mm)';
            app.XmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.XmmEditField.ValueChangedFcn = createCallbackFcn(app, @XmmEditFieldValueChanged, true);
            app.XmmEditField.Enable = 'off';
            app.XmmEditField.Value = 16;
            app.YmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.YmmEditFieldLabel.Text = '- Y (mm)';
            app.YmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.YmmEditField.ValueChangedFcn = createCallbackFcn(app, @YmmEditFieldValueChanged, true);
            app.YmmEditField.Enable = 'off';
            app.YmmEditField.Value = 16;
            app.EstimationofmappingtimeLabel = uilabel(app.PropertiesTab);
            app.EstimationofmappingtimeLabel.Tooltip = {'Mapping time may differ from the estimation. This estimation is based on the number of pixels (Determined by the mapping step and area dimensions) and by the waiting time of the arm (Found in the Source tab)'};
            app.EstimationofmappingtimeLabel.Text = 'Estimation of mapping time : ';
            app.MaximumheightmmofthesampleLabel = uilabel(app.PropertiesTab);
            app.MaximumheightmmofthesampleLabel.Text = 'Sample Maximum Height (mm)';
            app.SampleMaximumHeightmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.SampleMaximumHeightmmEditField.Enable = 'off';
            app.SampleMaximumHeightmmEditField.Value = 10;
            app.FirstmappingpointXpositionmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.FirstmappingpointXpositionmmEditFieldLabel.Text = 'First mapping point X position (mm)';
            app.FirstmappingpointXpositionmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.FirstmappingpointXpositionmmEditField.Enable = 'off';
            app.FirstmappingpointXpositionmmEditField.Tooltip = {'The idea is sound, but the implementation could be improved. I suggest replacing this with the manual coordinates we can give to the robot.'};
            app.FirstmappingpointXpositionmmEditField.Value = 150;
            app.FirstmappingpointYpositionmmEditFieldLabel = uilabel(app.PropertiesTab);
            app.FirstmappingpointYpositionmmEditFieldLabel.Text = 'First mapping point Y position (mm)';
            app.FirstmappingpointYpositionmmEditField = uieditfield(app.PropertiesTab, 'numeric');
            app.FirstmappingpointYpositionmmEditField.Enable = 'off';
            app.FirstmappingpointYpositionmmEditField.Tooltip = {'The idea is sound, but the implementation could be improved. I suggest replacing this with the manual coordinates we can give to the robot.'};
            app.FirstmappingpointYpositionmmEditField.Value = 2;
            app.DimensionsLabel = uilabel(app.PropertiesTab);
            app.DimensionsLabel.Tooltip = {'Mapping time may differ from the estimation. This estimation is based on the number of pixels (Determined by the mapping step and area dimensions) and by the waiting time of the arm (Found in the Laser tab)'};
            app.DimensionsLabel.Text = 'Dimensions:';
            app.RobotTab = uitab(app.TabGroup2);
            app.RobotTab.Title = 'Robot';
            app.RobotDropDownLabel = uilabel(app.RobotTab);
            app.RobotDropDownLabel.Text = 'Robot ';
            app.RobotDropDown = uidropdown(app.RobotTab);
            app.RobotConnexionB = uibutton(app.RobotTab, 'push');
            app.RobotConnexionB.ButtonPushedFcn = createCallbackFcn(app, @RobotConnexionBButtonPushed, true);
            app.RobotConnexionB.Text = 'Connect Robot';
            app.StateLampRobot = uilamp(app.RobotTab);
            app.DisconnectRobotButton = uibutton(app.RobotTab, 'push');
            app.DisconnectRobotButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectRobotButtonPushed, true);
            app.DisconnectRobotButton.Enable = 'off';
            app.DisconnectRobotButton.Text = 'Disconnect Robot';
            app.ResetButton = uibutton(app.RobotTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Visible = 'off';
            app.ResetButton.Tooltip = {'Used to clear error states'};
            app.ResetButton.Text = 'Reset';
            app.XEditFieldLabel = uilabel(app.RobotTab);
            app.XEditFieldLabel.Text = '- X (°)';
            app.XEditField = uieditfield(app.RobotTab, 'numeric');
            app.XEditField.Enable = 'off';
            app.XEditField.Value = 16;
            app.RotationLabel = uilabel(app.RobotTab);
            app.RotationLabel.Tooltip = {'Mapping time may differ from the estimation. This estimation is based on the number of pixels (Determined by the mapping step and area dimensions) and by the waiting time of the arm (Found in the Laser tab)'};
            app.RotationLabel.Text = 'Rotation';
            app.YEditFieldLabel = uilabel(app.RobotTab);
            app.YEditFieldLabel.Text = '- Y (°)';
            app.YEditField = uieditfield(app.RobotTab, 'numeric');
            app.YEditField.Enable = 'off';
            app.YEditField.Value = 16;
            app.RobotTab_2 = uitab(app.TabGroup2);
            app.RobotTab_2.Title = 'Robot';
            app.RobotDropDown_2Label = uilabel(app.RobotTab_2);
            app.RobotDropDown_2Label.Text = 'Robot ';
            app.RobotDropDown_2 = uidropdown(app.RobotTab_2);
            app.RobotConnexionB_2 = uibutton(app.RobotTab_2, 'push');
            app.RobotConnexionB_2.Text = 'Connect Robot';
            app.StateLampRobot_2 = uilamp(app.RobotTab_2);
            app.DisconnectRobotButton_2 = uibutton(app.RobotTab_2, 'push');
            app.DisconnectRobotButton_2.Enable = 'off';
            app.DisconnectRobotButton_2.Text = 'Disconnect Robot';
            app.ResetButton_2 = uibutton(app.RobotTab_2, 'push');
            app.ResetButton_2.Enable = 'off';
            app.ResetButton_2.Visible = 'off';
            app.ResetButton_2.Tooltip = {'Used to clear error states'};
            app.ResetButton_2.Text = 'Reset';
            app.XEditField_2Label = uilabel(app.RobotTab_2);
            app.XEditField_2Label.Text = '- X (°)';
            app.RXEditField = uieditfield(app.RobotTab_2, 'numeric');
            app.RXEditField.Limits = [0 180];
            app.RXEditField.Enable = 'off';
            app.RotationLabel_2 = uilabel(app.RobotTab_2);
            app.RotationLabel_2.Tooltip = {'Mapping time may differ from the estimation. This estimation is based on the number of pixels (Determined by the mapping step and area dimensions) and by the waiting time of the arm (Found in the Laser tab)'};
            app.RotationLabel_2.Text = 'Rotation';
            app.YEditField_2Label = uilabel(app.RobotTab_2);
            app.YEditField_2Label.Text = '- Y (°)';
            app.RYEditField = uieditfield(app.RobotTab_2, 'numeric');
            app.RYEditField.Limits = [0 180];
            app.RYEditField.Enable = 'off';
            app.RYEditField.Value = 180;
            app.ZEditFieldLabel = uilabel(app.RobotTab_2);
            app.ZEditFieldLabel.Text = '- Z (°)';
            app.RZEditField = uieditfield(app.RobotTab_2, 'numeric');
            app.RZEditField.Limits = [0 180];
            app.RZEditField.Enable = 'off';
            app.SensorTab = uitab(app.TabGroup2);
            app.SensorTab.Title = 'Sensor';
            app.SensorDropDownLabel = uilabel(app.SensorTab);
            app.SensorDropDownLabel.Text = 'Sensor';
            app.SensorDropDown = uidropdown(app.SensorTab);
            app.LaunchSensorCalibrationButton = uibutton(app.SensorTab, 'push');
            app.LaunchSensorCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchSensorCalibrationButtonPushed, true);
            app.LaunchSensorCalibrationButton.Enable = 'off';
            app.LaunchSensorCalibrationButton.Tooltip = {'Sensor calibration is only required for analogic sensors'};
            app.LaunchSensorCalibrationButton.Text = 'Launch Sensor Calibration';
            app.CalibrationstateLabel = uilabel(app.SensorTab);
            app.CalibrationstateLabel.Text = 'Sensor Calibration state :';
            app.SensorCalibrationstateLamp = uilamp(app.SensorTab);
            app.ConnectSensorButton = uibutton(app.SensorTab, 'push');
            app.ConnectSensorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSensorButtonPushed, true);
            app.ConnectSensorButton.Text = 'Connect Sensor';
            app.SensorstateLampLabel = uilabel(app.SensorTab);
            app.SensorstateLampLabel.Text = 'Sensor state :';
            app.SensorstateLamp = uilamp(app.SensorTab);
            app.SourceTab = uitab(app.TabGroup2);
            app.SourceTab.Title = 'Source';
            app.SourceDropDownLabel = uilabel(app.SourceTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.SourceTab);
            app.SourceDropDown.Items = {'Opotek IR Radiant', 'Opolette'};
            app.SourceDropDown.Value = 'Opotek IR Radiant';
            app.ConnectSourceButton = uibutton(app.SourceTab, 'push');
            app.ConnectSourceButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSourceButtonPushed, true);
            app.ConnectSourceButton.Text = 'Connect Source';
            app.StateLampOpotek = uilamp(app.SourceTab);
            app.DisconnectButton = uibutton(app.SourceTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect ';
            app.TemperatureButton = uibutton(app.SourceTab, 'push');
            app.TemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @TemperatureButtonPushed, true);
            app.TemperatureButton.Enable = 'off';
            app.TemperatureButton.Text = 'Temperature';
            app.AffichageTemperature = uilabel(app.SourceTab);
            app.AffichageTemperature.Text = 'Temperature : NO_DATA';
            app.TurnLampOnButton = uibutton(app.SourceTab, 'push');
            app.TurnLampOnButton.ButtonPushedFcn = createCallbackFcn(app, @TurnLampOnButtonPushed, true);
            app.TurnLampOnButton.Enable = 'off';
            app.TurnLampOnButton.Text = 'Turn Lamp On ';
            app.Lamp_LampONorOFF = uilamp(app.SourceTab);
            app.TurnLampOffButton = uibutton(app.SourceTab, 'push');
            app.TurnLampOffButton.ButtonPushedFcn = createCallbackFcn(app, @TurnLampOffButtonPushed, true);
            app.TurnLampOffButton.Enable = 'off';
            app.TurnLampOffButton.Text = 'Turn Lamp Off';
            app.GetStateButton = uibutton(app.SourceTab, 'push');
            app.GetStateButton.ButtonPushedFcn = createCallbackFcn(app, @GetStateButtonPushed, true);
            app.GetStateButton.Enable = 'off';
            app.GetStateButton.Text = 'Get State';
            app.AffichageState = uilabel(app.SourceTab);
            app.AffichageState.Text = 'State : NO_DATA';
            app.FocaldistancemmEditFieldLabel = uilabel(app.SourceTab);
            app.FocaldistancemmEditFieldLabel.Text = 'Focal distance (mm) ';
            app.FocaldistancemmEditField = uieditfield(app.SourceTab, 'numeric');
            app.FocaldistancemmEditField.ValueChangedFcn = createCallbackFcn(app, @FocaldistancemmEditFieldValueChanged, true);
            app.FocaldistancemmEditField.Enable = 'off';
            app.FocaldistancemmEditField.Tooltip = {'Height at which the robot must be positioned in order for the spot to be at focal distance. This setting is relevant to focused lasers only.'};
            app.FocaldistancemmEditField.Value = 80;
            app.ContinuousAcquisitionCheckBox = uicheckbox(app.SourceTab);
            app.ContinuousAcquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousAcquisitionCheckBoxValueChanged, true);
            app.ContinuousAcquisitionCheckBox.Enable = 'off';
            app.ContinuousAcquisitionCheckBox.Tooltip = {'When turned on, the laser will fire continuously instead of performing individual bursts for each pixel. Note that this does not alter the behaviour of the robot arm.'};
            app.ContinuousAcquisitionCheckBox.Text = 'Continuous Acquisition';
            app.EnergylevelVSpinnerLabel = uilabel(app.SourceTab);
            app.EnergylevelVSpinnerLabel.Text = 'Energy level (V)';
            app.EnergylevelVSpinner = uispinner(app.SourceTab);
            app.EnergylevelVSpinner.ValueChangedFcn = createCallbackFcn(app, @EnergylevelVSpinnerValueChanged, true);
            app.EnergylevelVSpinner.Enable = 'off';
            app.EnergylevelVSpinner.Value = 682;
            app.LampVoltageSet = uilamp(app.SourceTab);
            app.TimebetweenbeamburstssEditFieldLabel = uilabel(app.SourceTab);
            app.TimebetweenbeamburstssEditFieldLabel.Text = 'Time between beam bursts (s)';
            app.TimebetweenbeamburstssEditField = uieditfield(app.SourceTab, 'numeric');
            app.TimebetweenbeamburstssEditField.ValueChangedFcn = createCallbackFcn(app, @TimebetweenbeamburstssEditFieldValueChanged, true);
            app.TimebetweenbeamburstssEditField.Enable = 'off';
            app.TimebetweenbeamburstssEditField.Value = 3;
            app.PulsesperburstEditFieldLabel = uilabel(app.SourceTab);
            app.PulsesperburstEditFieldLabel.Text = 'Pulses per burst';
            app.PulsesperburstEditField = uieditfield(app.SourceTab, 'numeric');
            app.PulsesperburstEditField.ValueChangedFcn = createCallbackFcn(app, @PulsesperburstEditFieldValueChanged, true);
            app.PulsesperburstEditField.Enable = 'off';
            app.PulsesperburstEditField.Value = 3;
            app.MassSpectrometerTab = uitab(app.TabGroup2);
            app.MassSpectrometerTab.Title = 'Mass Spectrometer';
            app.MassSpectrometerTrigerringDropDownLabel = uilabel(app.MassSpectrometerTab);
            app.MassSpectrometerTrigerringDropDownLabel.Text = 'Mass Spectrometer Trigerring :';
            app.MassSpectrometerTrigerringDropDown = uidropdown(app.MassSpectrometerTab);
            app.MassSpectrometerTrigerringDropDown.Items = {'Option 1'};
            app.MassSpectrometerTrigerringDropDown.Tooltip = {'Synchronizes the start of an MS acquisition with the robot and the laser. '};
            app.StartTab = uitab(app.TabGroup2);
            app.StartTab.Title = 'Start';
            app.GotoMappingPositionButton = uibutton(app.StartTab, 'push');
            app.GotoMappingPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GotoMappingPositionButtonPushed, true);
            app.GotoMappingPositionButton.Tooltip = {'Moves the robot to its starting position for data acquisition.'};
            app.GotoMappingPositionButton.Text = 'Go to Mapping Position';
            app.StatusWaitingLabel = uilabel(app.StartTab);
            app.StatusWaitingLabel.Text = 'Status: Waiting...';
            app.LaunchMappingButton = uibutton(app.StartTab, 'push');
            app.LaunchMappingButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMappingButtonPushed, true);
            app.LaunchMappingButton.Text = 'Launch Mapping';
            app.QuittoMenuButton_2 = uibutton(app.StartTab, 'push');
            app.QuittoMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @QuittoMenuButton_2Pushed, true);
            app.QuittoMenuButton_2.Text = 'Quit to Menu';
            app.RobotStateDisconnectedLabel = uilabel(app.StartTab);
            app.RobotStateDisconnectedLabel.Text = 'Robot State: Disconnected';
            app.SourceStateOffLabel = uilabel(app.StartTab);
            app.SourceStateOffLabel.Text = 'Source State: Off';
            app.MappingModeTopographyOnlyLabel = uilabel(app.StartTab);
            app.MappingModeTopographyOnlyLabel.Text = 'Mapping Mode: Topography Only';
            app.MapDimensionsmmLabel = uilabel(app.StartTab);
            app.MapDimensionsmmLabel.Text = 'Map Dimensions (mm)';
            app.Resolutionm500Label = uilabel(app.StartTab);
            app.Resolutionm500Label.Text = 'Resolution (µm): 500';
            app.SensorStateOffLabel = uilabel(app.StartTab);
            app.SensorStateOffLabel.Text = 'Sensor State: Off';
            app.EstimatedDurationLabel = uilabel(app.StartTab);
            app.EstimatedDurationLabel.Text = 'Estimated Duration: ';
            app.XPositionEditFieldLabel = uilabel(app.StartTab);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.StartTab, 'numeric');
            app.XPositionEditField.Enable = 'off';
            app.XPositionEditField.Value = 150;
            app.YPositionEditFieldLabel = uilabel(app.StartTab);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.StartTab, 'numeric');
            app.YPositionEditField.Enable = 'off';
            app.YPositionEditField.Value = 2;
            app.ZPositionEditFieldLabel = uilabel(app.StartTab);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.StartTab, 'numeric');
            app.ZPositionEditField.Enable = 'off';
            app.ZPositionEditField.Value = 100;
            app.MoveRobotButton = uibutton(app.StartTab, 'push');
            app.MoveRobotButton.ButtonPushedFcn = createCallbackFcn(app, @MoveRobotButtonPushed, true);
            app.MoveRobotButton.Enable = 'off';
            app.MoveRobotButton.Text = 'Move Robot';
            app.LaserBurstButton = uibutton(app.StartTab, 'push');
            app.LaserBurstButton.ButtonPushedFcn = createCallbackFcn(app, @LaserBurstButtonPushed, true);
            app.LaserBurstButton.Enable = 'off';
            app.LaserBurstButton.Tooltip = {'Fires a laser burst. The number of shots is specified in the Source tab. Can be used for energy measurements or determination. the focal distance.'};
            app.LaserBurstButton.Text = 'Laser Burst ';
            app.EMERGENCYSTOPButton = uibutton(app.StartTab, 'state');
            app.EMERGENCYSTOPButton.ValueChangedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonValueChanged, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.FocaldistancemmEditField_2Label = uilabel(app.StartTab);
            app.FocaldistancemmEditField_2Label.Text = 'Focal distance (mm) ';
            app.FocaldistancemmEditField_2 = uieditfield(app.StartTab, 'numeric');
            app.FocaldistancemmEditField_2.ValueChangedFcn = createCallbackFcn(app, @FocaldistancemmEditField_2ValueChanged, true);
            app.FocaldistancemmEditField_2.Enable = 'off';
            app.FocaldistancemmEditField_2.Tooltip = {'Height at which the robot must be positioned in order for the spot to be at focal distance. This setting is relevant to focused lasers only.'};
            app.FocaldistancemmEditField_2.Value = 80;
            app.FilenameLabel = uilabel(app.StartTab);
            app.FilenameLabel.Text = 'Filename: ';
            app.Label_2 = uilabel(app.StartTab);
            app.Label_2.Text = '';
            app.XmmEditField_2Label = uilabel(app.StartTab);
            app.XmmEditField_2Label.Text = '- X (mm)';
            app.XmmEditField_2 = uieditfield(app.StartTab, 'numeric');
            app.XmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @XmmEditField_2ValueChanged, true);
            app.XmmEditField_2.Enable = 'off';
            app.XmmEditField_2.Value = 16;
            app.YmmEditField_2Label = uilabel(app.StartTab);
            app.YmmEditField_2Label.Text = '- Y (mm)';
            app.YmmEditField_2 = uieditfield(app.StartTab, 'numeric');
            app.YmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @YmmEditField_2ValueChanged, true);
            app.YmmEditField_2.Enable = 'off';
            app.YmmEditField_2.Value = 16;
            app.LogTab = uitab(app.TabGroup2);
            app.LogTab.Title = 'Log';
            app.TextArea = uitextarea(app.LogTab);
            app.TextArea.Editable = 'off';
            app.TextAreaLabel = uilabel(app.LogTab);
            app.TextAreaLabel.Enable = 'off';
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.STORMMSIModeSelectionPanel = uipanel(app.STORMMSIUIFigure);
            app.STORMMSIModeSelectionPanel.Title = 'STORM-MSI - Mode Selection';
            app.DataAcquisitionButton_2 = uibutton(app.STORMMSIModeSelectionPanel, 'push');
            app.DataAcquisitionButton_2.ButtonPushedFcn = createCallbackFcn(app, @DataAcquisitionButton_2Pushed, true);
            app.DataAcquisitionButton_2.Text = 'Data Acquisition';
            app.DataProcessingButton_2 = uibutton(app.STORMMSIModeSelectionPanel, 'push');
            app.DataProcessingButton_2.ButtonPushedFcn = createCallbackFcn(app, @DataProcessingButton_2Pushed, true);
            app.DataProcessingButton_2.Text = 'Data Processing';
            % Show the figure after all components are created
            app.STORMMSIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMG_MSI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.STORMMSIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.STORMMSIUIFigure)
        end
    end
end